classdef F1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        UPLOADVIDEOButton               matlab.ui.control.Button
        UPLOADSIMDATAButton             matlab.ui.control.Button
        UPLOADTELEMETRYDATAButton       matlab.ui.control.Button
        SimDataFilePath                 matlab.ui.control.TextArea
        TelemetryDataFilePath           matlab.ui.control.TextArea
        RESETButton                     matlab.ui.control.Button
        EXITButton                      matlab.ui.control.Button
        PlottingTab                     matlab.ui.container.Tab
        Tree                            matlab.ui.container.Tree
        GeneralPlotsNode                matlab.ui.container.TreeNode
        GlobalPositionXvsYNode          matlab.ui.container.TreeNode
        GGDiagrama_xvsa_yNode           matlab.ui.container.TreeNode
        TireForcesNode                  matlab.ui.container.TreeNode
        LongitudinalForceFxVsTimetNode  matlab.ui.container.TreeNode
        LateralForceFyVsTimetNode       matlab.ui.container.TreeNode
        AligningMomentMzVsTimetNode     matlab.ui.container.TreeNode
        OverturningMomentMxVsTimetNode  matlab.ui.container.TreeNode
        NormalLoadsFzVsTimetNode        matlab.ui.container.TreeNode
        TireStatesNode                  matlab.ui.container.TreeNode
        SteerAngleDeltaVsTimetNode      matlab.ui.container.TreeNode
        WheelRateOmegaVsTimetNode       matlab.ui.container.TreeNode
        SlipAngleAlphaVsTimetNode       matlab.ui.container.TreeNode
        InflationPressureP_iVsTimetNode  matlab.ui.container.TreeNode
        CamberGammaVsTimetNode          matlab.ui.container.TreeNode
        SlipRatioKappaVsTimetNode       matlab.ui.container.TreeNode
        BrakingStatesNode               matlab.ui.container.TreeNode
        DrivelineStatesNode             matlab.ui.container.TreeNode
        SteeringStatesNode              matlab.ui.container.TreeNode
        PlottingAxis1                   matlab.ui.control.UIAxes
        EditFieldLabel                  matlab.ui.control.Label
        TestField                       matlab.ui.control.EditField
        TestButton                      matlab.ui.control.Button
        PlottingAxis2                   matlab.ui.control.UIAxes
        flagvalueLabel                  matlab.ui.control.Label
        TestFlag                        matlab.ui.control.EditField
        PlottingAxis3                   matlab.ui.control.UIAxes
        PlottingAxis4                   matlab.ui.control.UIAxes
        PlottingAxis5                   matlab.ui.control.UIAxes
        PlottingAxis6                   matlab.ui.control.UIAxes
        SimulationDataTab               matlab.ui.container.Tab
        SimulationDataTable             matlab.ui.control.Table
        TelemetryDataTab                matlab.ui.container.Tab
        TelemetryDataTable              matlab.ui.control.Table
    end

    
    properties (Access = public)
        % --------INITIALIZE FLAGS---------
        flag_GlobalPositionXvsYNode;
        flag_GGDiagram_xvsa_yNode;
        flag_LongitudinalForceFxVsTimetNode;
        flag_LateralForceFyVsTimetNode;
        flag_AligningMomentMzVsTimetNode;
        flag_OverturningMomentMxVsTimetNode;
        flag_NormalLoadsFzVsTimetNode;
        flag_SteerAngleDeltaVsTimetNode;
        flag_WheelRateOmegaVsTimetNode;
        flag_SlipAngleAlphaVsTimetNode;
        flag_SlipRatioKappaVsTimetNode;
        flag_CamberGammaVsTimetNode;
        flag_InflationPressureP_iVsTimetNode;
        %------END FLAGS-----------


    end
    
    methods (Access = public)


        function setFlags(app,booleansValue)
            % INPUT >> BOOL VALUE TO SET ALL FLAGS TO
            % OUTPUT >> NONE, SETS THE FLAGS TO BOOL VALUE
            app.flag_GlobalPositionXvsYNode = booleansValue;
            app.flag_GGDiagram_xvsa_yNode = booleansValue;
            app.flag_LongitudinalForceFxVsTimetNode = booleansValue;
            app.flag_LateralForceFyVsTimetNode = booleansValue;
            app.flag_AligningMomentMzVsTimetNode = booleansValue;
            app.flag_OverturningMomentMxVsTimetNode = booleansValue;
            app.flag_NormalLoadsFzVsTimetNode = booleansValue;
            app.flag_SteerAngleDeltaVsTimetNode = booleansValue;
            app.flag_WheelRateOmegaVsTimetNode = booleansValue;
            app.flag_SlipAngleAlphaVsTimetNode = booleansValue;
            app.flag_SlipRatioKappaVsTimetNode = booleansValue;
            app.flag_CamberGammaVsTimetNode = booleansValue;
            app.flag_InflationPressureP_iVsTimetNode = booleansValue;


        end
        
        function flagsState = getFlags(app)
            % INPUT >> NONE
            % OUTPUT >> CREATES AN ARRAY OF THE FLAGS
            flagsState = [app.flag_GlobalPositionXvsYNode, app.flag_GGDiagram_xvsa_yNode, app.flag_LongitudinalForceFxVsTimetNode, app.flag_LateralForceFyVsTimetNode, ...
                app.flag_OverturningMomentMxVsTimetNode, app.flag_NormalLoadsFzVsTimetNode, app.flag_SteerAngleDeltaVsTimetNode, app.flag_WheelRateOmegaVsTimetNode,...
                app.flag_InflationPressureP_iVsTimetNode, app.flag_CamberGammaVsTimetNode, app.flag_SlipAngleAlphaVsTimetNode, app.flag_SlipRatioKappaVsTimetNode,...
                app.flag_AligningMomentMzVsTimetNode];
            
        end
        
        function turnOnAxis(app,numberOfAxis)
            % INPUT >> NUMBER OF AXIS TO DISPLAY
            % OUTPTU >> DISPALYS THE NUMBER OF AXIS SPECIFIED IN ORDER FROM
            % FIRST TO SIXTH
            app.TestFlag.Value = string(numberOfAxis);
            switch numberOfAxis
                case 0
                    app.PlottingAxis1.Visible = 'off';
                    app.PlottingAxis2.Visible = 'off';
                    app.PlottingAxis3.Visible = 'off';
                    app.PlottingAxis4.Visible = 'off';
                    app.PlottingAxis5.Visible = 'off';
                    app.PlottingAxis6.Visible = 'off';
                case 1
                    app.PlottingAxis1.Visible = 'on';
                    app.PlottingAxis2.Visible = 'off';
                    app.PlottingAxis3.Visible = 'off';
                    app.PlottingAxis4.Visible = 'off';
                    app.PlottingAxis5.Visible = 'off';
                    app.PlottingAxis6.Visible = 'off';
                case 2
                    app.PlottingAxis1.Visible = 'on';
                    app.PlottingAxis2.Visible = 'on';
                    app.PlottingAxis3.Visible = 'off';
                    app.PlottingAxis4.Visible = 'off';
                    app.PlottingAxis5.Visible = 'off';
                    app.PlottingAxis6.Visible = 'off';
                case 3
                    app.PlottingAxis1.Visible = 'on';
                    app.PlottingAxis2.Visible = 'on';
                    app.PlottingAxis3.Visible = 'on';
                    app.PlottingAxis4.Visible = 'off';
                    app.PlottingAxis5.Visible = 'off';
                    app.PlottingAxis6.Visible = 'off';
                case 4
                    app.PlottingAxis1.Visible = 'on';
                    app.PlottingAxis2.Visible = 'on';
                    app.PlottingAxis3.Visible = 'on';
                    app.PlottingAxis4.Visible = 'on';
                    app.PlottingAxis5.Visible = 'off';
                    app.PlottingAxis6.Visible = 'off';
                case 5
                    app.PlottingAxis1.Visible = 'on';
                    app.PlottingAxis2.Visible = 'on';
                    app.PlottingAxis3.Visible = 'on';
                    app.PlottingAxis4.Visible = 'on';
                    app.PlottingAxis5.Visible = 'on';
                    app.PlottingAxis6.Visible = 'off';
                case 6
                    app.PlottingAxis1.Visible = 'on';
                    app.PlottingAxis2.Visible = 'on';
                    app.PlottingAxis3.Visible = 'on';
                    app.PlottingAxis4.Visible = 'on';
                    app.PlottingAxis5.Visible = 'on';
                    app.PlottingAxis6.Visible = 'on';
            end
            
            
        end
        
        
        function plotGlobalPisitionXvsYNode(app, plotAxis)
            
            x1 = app.SimulationDataTable.Data.sppeeeed;
            y1 = app.SimulationDataTable.Data.viagra;
            
            switch plotAxis
                case 1
                    plot(app.PlottingAxis1,x1,y1);
                case 2
                    plot(app.PlottingAxis2,x1,y1);
                case 3
                    plot(app.PlottingAxis3,x1,y1);
                case 4
                    plot(app.PlottingAxis4,x1,y1);
                case 5
                    plot(app.PlottingAxis5,x1,y1);
                case 6
                    plot(app.PlottingAxis6,x1,y1);
            end
            
        end
        
        function plotGGDiagram_xvsa_yNode(app, plotAxis)
            
            y1 = app.SimulationDataTable.Data.sppeeeed;
            x1 = app.SimulationDataTable.Data.viagra;
            
            switch plotAxis
                case 1
                    plot(app.PlottingAxis1,x1,y1);
                case 2
                    plot(app.PlottingAxis2,x1,y1);
                case 3
                    plot(app.PlottingAxis3,x1,y1);
                case 4
                    plot(app.PlottingAxis4,x1,y1);
                case 5
                    plot(app.PlottingAxis5,x1,y1);
                case 6
                    plot(app.PlottingAxis6,x1,y1);
            end
            
        end


        function graphNode(app,numberOfGraphsToPlot)
            
            
            switch numberOfGraphsToPlot
                case 1
                    if app.flag_GlobalPositionXvsYNode == true
                        plotGlobalPisitionXvsYNode(app,1);
                    end
                    
                    if app.flag_GGDiagram_xvsa_yNode == true
                        plotGGDiagram_xvsa_yNode(app,1);
                    end
                case 2
                    
                    
                case 3
                    
                case 4
                case 5
                case 6


            end


        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UPLOADTELEMETRYDATAButton
        function UPLOADTELEMETRYDATAButtonPushed(app, event)
            %---UPLOAD EXCEL SHEET------
            [file, path] = uigetfile('*.xlsx');
            if isequal(file,0)
                msbox('Input an Excel file motherfucker')
            else
                x = readtable(fullfile(path,file));
                app.TelemetryDataTable.Data = x;
                app.TelemetryDataFilePath.Value = [path,file]; % OUTPUT THE FILE PATH INTO TEXTBOX
                
            end
            %----- END OF UPLOAD-------
            
            setFlags(app,false); % SET ALL FLAGS TO FALSE
            turnOnAxis(app,0); % HIDE ALL AXES
            
            
        end

        % Button pushed function: UPLOADSIMDATAButton
        function UPLOADSIMDATAButtonPushed(app, event)
            %---UPLOAD EXCEL SHEET------
            [file, path] = uigetfile('*.xlsx');
            if isequal(file,0)
                msbox('Input an Excel file motherfucker')
            else
                t = readtable(fullfile(path,file));
                app.SimulationDataTable.Data = t;
                app.SimDataFilePath.Value =[path,file]; % OUTPUT THE FILE PATH INTO TEXTBOX
            end
            %-----END OF UPLOAD------
            
            setFlags(app,false); % SET ALL FLAGS TO FALSE
            turnOnAxis(app,0); % HIDE ALL AXES


        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            app.Tree.Multiselect = 'on'; % ALLOW MULTISELECTION OF NODES
            
            
            %             axisTitle = selectedNodes.Text;  % GRAB THE NAME OF THE NODE
            %
            %             app.PlottingAxis1.Title.String = (axisTitle);        % CHANGE THE TITLE
            %


            %------- DECLARING STRINGS OF THE NODES ------------------------
            string_GlobalPostionXvs = 'Global Position ( X vs. Y)';
            string_GGDiagram_xvsa_y = 'G-G Diagram (a_{x} vs a_{y})';
            string_LongitudinalForceFxVsTimet = 'Longitudinal Force (Fx) Vs. Time (t)';
            string_LateralForceFyVsTimet = 'Lateral Force (Fy) Vs. Time (t)';
            string_AligningMomentMzVsTimet = 'Aligning Moment (Mz) Vs. Time (t)';
            string_OverturningMomentMxVsTimet = 'Overturning Moment (Mx) Vs. Time (t)';
            string_NormalLoadsFzVsTimet = 'Normal Loads (Fz) Vs. Time (t)';
            string_SteerAngleDeltaVsTimet = 'Steer Angle (Delta) Vs. Time (t)';
            string_WheelRateOmegaVsTimet = 'Wheel Rate (Omega) Vs. Time (t)';
            string_SlipAngleAlphaVsTimet = 'Slip Angle (Alpha) Vs. Time (t)';
            string_SlipRatioKappaVsTimet = 'Slip Ratio (Kappa) Vs. Time (t)';
            string_CamberGammaVsTimet = 'Camber (Gamma) Vs. Time (t)';
            string_InflationPressureP_iVsTimet = 'Inflation Pressure (P_{i}) Vs. Time (t)';
            %------- END OF DECLARING STRINGS OF NODES --------
            
            selectedNode = selectedNodes.Text;
            
            
            %---FLAG TRIGGER-------
            if strcmp(selectedNode,string_GlobalPostionXvs) == true
                app.flag_GlobalPositionXvsYNode = ~app.flag_GlobalPositionXvsYNode;
            end
            
            if strcmp(selectedNode,string_GGDiagram_xvsa_y) == true
                app.flag_GGDiagram_xvsa_yNode = ~app.flag_GGDiagram_xvsa_yNode;
            end
            
            if strcmp(selectedNode,string_LongitudinalForceFxVsTimet) == true
                app.flag_LongitudinalForceFxVsTimetNode = ~app.flag_LongitudinalForceFxVsTimetNode;
            end
            
            if strcmp(selectedNode,string_LateralForceFyVsTimet) == true
                app.flag_LateralForceFyVsTimetNode = ~app.flag_LateralForceFyVsTimetNode;
            end
            
            if strcmp(selectedNode,string_AligningMomentMzVsTimet) == true
                app.flag_AligningMomentMzVsTimetNode = ~app.flag_AligningMomentMzVsTimetNode;
            end
            
            if strcmp(selectedNode,string_OverturningMomentMxVsTimet) == true
                app.flag_OverturningMomentMxVsTimetNode = ~app.flag_OverturningMomentMxVsTimetNode;
            end
            
            if strcmp(selectedNode,string_NormalLoadsFzVsTimet) == true
                app.flag_NormalLoadsFzVsTimetNode = ~app.flag_NormalLoadsFzVsTimetNode;
            end
            
            if strcmp(selectedNode,string_SteerAngleDeltaVsTimet) == true
                app.flag_SteerAngleDeltaVsTimetNode = ~app.flag_SteerAngleDeltaVsTimetNode;
            end
            
            if strcmp(selectedNode,string_WheelRateOmegaVsTimet) == true
                app.flag_WheelRateOmegaVsTimetNode = ~app.flag_WheelRateOmegaVsTimetNode;
            end
            
            if strcmp(selectedNode,string_SlipAngleAlphaVsTimet) == true
                app.flag_SlipAngleAlphaVsTimetNode = ~app.flag_SlipAngleAlphaVsTimetNode;
            end
            
            if strcmp(selectedNode,string_SlipRatioKappaVsTimet) == true
                app.flag_SlipRatioKappaVsTimetNode = ~app.flag_SlipRatioKappaVsTimetNode;
            end
            
            if strcmp(selectedNode,string_CamberGammaVsTimet) == true
                app.flag_CamberGammaVsTimetNode = ~app.flag_CamberGammaVsTimetNode;
            end
            
            if strcmp(selectedNode, string_InflationPressureP_iVsTimet) == true
                app.flag_InflationPressureP_iVsTimetNode = ~app.flag_InflationPressureP_iVsTimetNode;
            end
            
            %---END OF FLAG TRIGGER-------
            
            %
            %             %----TEST GRAPH------
            %             if  app.flag_GlobalPositionXvsYNode == true
            %
            %
            %                 xTitle = (app.SimulationDataTable.Data.Properties.VariableNames{1,1});
            %                 yTitle = (app.SimulationDataTable.Data.Properties.VariableNames{1,2});
            %
            %                 app.TestField.Value = class(xTitle);
            %                 x1 = app.SimulationDataTable.Data.sppeeeed;
            %                 y1 = app.SimulationDataTable.Data.viagra;
            %                 plot(app.PlottingAxis1,x1,y1);
            %             else
            %                 cla(app.PlottingAxis1,'reset');
            %
            %             end
            %
            %             %---END OF TEST GRAPH -----


            stateFlags = getFlags(app); % GET THE FLAGS VECTOR
            
            numberOfGraphs = sum(stateFlags); % GET NUMBER OF GRAPHS NEEDED BASED ON NUMBER OF FLAGS
            
            %        app.TestFlag.Value = string(numberOfGraphs);
            
            
            %----TURN ON NUMBER OF GRAPHS-----
            switch numberOfGraphs
                %% TURNS ON NUMBER OF GRAPHS BASED OFF OF NUMBR OF FLAGS
                case 0
                    turnOnAxis(app,0)
                case 1
                    turnOnAxis(app,1)
                    graphNode(app,1)
                    
                    
                case 2
                    turnOnAxis(app,2)
                    graphNode(app,2)
                case 3
                    turnOnAxis(app,3)
                    graphNode(app,3)
                case 4
                    turnOnAxis(app,4)
                    graphNode(app,4)
                case 5
                    turnOnAxis(app,5)
                    graphNode(app,5)
                case 6
                    turnOnAxis(app,6)
                    graphNode(app,6)
                otherwise
                    disp('other')
            end
            %----END OF NUMBER OF GRAPHS-----


        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            
            
            app.UIFigure.AutoResizeChildren = 'off';


        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            closereq;  % CLOSE APP
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            set(app.SimulationDataTable, 'Data', {}); % CLEAR SIM DATA TABLE
            set(app.TelemetryDataTable, 'Data', {});  % CLEAR TEL DATA TABLE
            app.TelemetryDataFilePath.Value = '';     % CLEAR TEL FILE PATH
            app.SimDataFilePath.Value = '';           % CLEAR SIM FILE PATH
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.UPLOADVIDEOButton = uibutton(app.HomeTab, 'push');
            app.UPLOADVIDEOButton.Text = 'UPLOAD VIDEO';
            app.UPLOADSIMDATAButton = uibutton(app.HomeTab, 'push');
            app.UPLOADSIMDATAButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOADSIMDATAButtonPushed, true);
            app.UPLOADSIMDATAButton.Text = 'UPLOAD SIM DATA';
            app.UPLOADTELEMETRYDATAButton = uibutton(app.HomeTab, 'push');
            app.UPLOADTELEMETRYDATAButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOADTELEMETRYDATAButtonPushed, true);
            app.UPLOADTELEMETRYDATAButton.Text = {'UPLOAD '; 'TELEMETRY DATA'};
            app.SimDataFilePath = uitextarea(app.HomeTab);
            app.SimDataFilePath.Editable = 'off';
            app.TelemetryDataFilePath = uitextarea(app.HomeTab);
            app.TelemetryDataFilePath.Editable = 'off';
            app.RESETButton = uibutton(app.HomeTab, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.EXITButton = uibutton(app.HomeTab, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            app.PlottingTab = uitab(app.TabGroup);
            app.PlottingTab.Title = 'Plotting';
            app.Tree = uitree(app.PlottingTab);
            app.Tree.Multiselect = 'on';
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.GeneralPlotsNode = uitreenode(app.Tree);
            app.GeneralPlotsNode.Text = 'General Plots';
            app.GlobalPositionXvsYNode = uitreenode(app.GeneralPlotsNode);
            app.GlobalPositionXvsYNode.Text = 'Global Position ( X vs. Y)';
            app.GGDiagrama_xvsa_yNode = uitreenode(app.GeneralPlotsNode);
            app.GGDiagrama_xvsa_yNode.Text = 'G-G Diagram (a_{x} vs a_{y})';
            app.TireForcesNode = uitreenode(app.Tree);
            app.TireForcesNode.Text = 'Tire Forces';
            app.LongitudinalForceFxVsTimetNode = uitreenode(app.TireForcesNode);
            app.LongitudinalForceFxVsTimetNode.Text = 'Longitudinal Force (Fx) Vs. Time (t)';
            app.LateralForceFyVsTimetNode = uitreenode(app.TireForcesNode);
            app.LateralForceFyVsTimetNode.Text = 'Lateral Force (Fy) Vs. Time (t)';
            app.AligningMomentMzVsTimetNode = uitreenode(app.TireForcesNode);
            app.AligningMomentMzVsTimetNode.Text = 'Aligning Moment (Mz) Vs. Time (t)';
            app.OverturningMomentMxVsTimetNode = uitreenode(app.TireForcesNode);
            app.OverturningMomentMxVsTimetNode.Text = 'Overturning Moment (Mx) Vs. Time (t)';
            app.NormalLoadsFzVsTimetNode = uitreenode(app.TireForcesNode);
            app.NormalLoadsFzVsTimetNode.Text = 'Normal Loads (Fz) Vs. Time (t)';
            app.TireStatesNode = uitreenode(app.Tree);
            app.TireStatesNode.Text = 'Tire States';
            app.SteerAngleDeltaVsTimetNode = uitreenode(app.TireStatesNode);
            app.SteerAngleDeltaVsTimetNode.Text = 'Steer Angle (Delta) Vs. Time (t)';
            app.WheelRateOmegaVsTimetNode = uitreenode(app.TireStatesNode);
            app.WheelRateOmegaVsTimetNode.Text = 'Wheel Rate (Omega) Vs. Time (t)';
            app.SlipAngleAlphaVsTimetNode = uitreenode(app.TireStatesNode);
            app.SlipAngleAlphaVsTimetNode.Text = 'Slip Angle (Alpha) Vs. Time (t)';
            app.InflationPressureP_iVsTimetNode = uitreenode(app.TireStatesNode);
            app.InflationPressureP_iVsTimetNode.Text = 'Inflation Pressure (P_{i}) Vs. Time (t)';
            app.CamberGammaVsTimetNode = uitreenode(app.TireStatesNode);
            app.CamberGammaVsTimetNode.Text = 'Camber (Gamma) Vs. Time (t)';
            app.SlipRatioKappaVsTimetNode = uitreenode(app.TireStatesNode);
            app.SlipRatioKappaVsTimetNode.Text = 'Slip Ratio (Kappa) Vs. Time (t)';
            app.BrakingStatesNode = uitreenode(app.Tree);
            app.BrakingStatesNode.Text = 'Braking States';
            app.DrivelineStatesNode = uitreenode(app.Tree);
            app.DrivelineStatesNode.Text = 'Driveline States';
            app.SteeringStatesNode = uitreenode(app.Tree);
            app.SteeringStatesNode.Text = 'Steering States';
            app.PlottingAxis1 = uiaxes(app.PlottingTab);
            title(app.PlottingAxis1, 'Title')
            xlabel(app.PlottingAxis1, 'X')
            ylabel(app.PlottingAxis1, 'Y')
            app.EditFieldLabel = uilabel(app.PlottingTab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.TestField = uieditfield(app.PlottingTab, 'text');
            app.TestButton = uibutton(app.PlottingTab, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.PlottingAxis2 = uiaxes(app.PlottingTab);
            title(app.PlottingAxis2, 'Title')
            xlabel(app.PlottingAxis2, 'X')
            ylabel(app.PlottingAxis2, 'Y')
            app.flagvalueLabel = uilabel(app.PlottingTab);
            app.flagvalueLabel.Text = 'flag value';
            app.TestFlag = uieditfield(app.PlottingTab, 'text');
            app.PlottingAxis3 = uiaxes(app.PlottingTab);
            title(app.PlottingAxis3, 'Title')
            xlabel(app.PlottingAxis3, 'X')
            ylabel(app.PlottingAxis3, 'Y')
            app.PlottingAxis4 = uiaxes(app.PlottingTab);
            title(app.PlottingAxis4, 'Title')
            xlabel(app.PlottingAxis4, 'X')
            ylabel(app.PlottingAxis4, 'Y')
            app.PlottingAxis5 = uiaxes(app.PlottingTab);
            title(app.PlottingAxis5, 'Title')
            xlabel(app.PlottingAxis5, 'X')
            ylabel(app.PlottingAxis5, 'Y')
            app.PlottingAxis6 = uiaxes(app.PlottingTab);
            title(app.PlottingAxis6, 'Title')
            xlabel(app.PlottingAxis6, 'X')
            ylabel(app.PlottingAxis6, 'Y')
            app.SimulationDataTab = uitab(app.TabGroup);
            app.SimulationDataTab.Title = 'Simulation Data';
            app.SimulationDataTable = uitable(app.SimulationDataTab);
            app.SimulationDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SimulationDataTable.RowName = {};
            app.TelemetryDataTab = uitab(app.TabGroup);
            app.TelemetryDataTab.Title = 'Telemetry Data';
            app.TelemetryDataTable = uitable(app.TelemetryDataTab);
            app.TelemetryDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TelemetryDataTable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = F1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end