classdef SpaceMonkeysV7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        HomeTab                       matlab.ui.container.Tab
        UIAxesGif                     matlab.ui.control.UIAxes
        EncryptButton                 matlab.ui.control.Button
        DecryptButton                 matlab.ui.control.Button
        ExitButton                    matlab.ui.control.Button
        SPACEMONKEYSLabel             matlab.ui.control.Label
        InfoButton                    matlab.ui.control.Button
        EncryptTab                    matlab.ui.container.Tab
        HomeButtonEncrypt             matlab.ui.control.Button
        UIAxesUserImage               matlab.ui.control.UIAxes
        UploadPlainImageButton        matlab.ui.control.Button
        SelectNumofSecretsDropDownLabel  matlab.ui.control.Label
        SelectNumofSecretsDropDown    matlab.ui.control.DropDown
        UploadButtonHidden1           matlab.ui.control.Button
        UIAxesHidden1                 matlab.ui.control.UIAxes
        UploadButtonHidden2           matlab.ui.control.Button
        UploadButtonHidden3           matlab.ui.control.Button
        UIAxesHidden2                 matlab.ui.control.UIAxes
        UIAxesHidden3                 matlab.ui.control.UIAxes
        UIAxesHidden4                 matlab.ui.control.UIAxes
        UIAxesHidden7                 matlab.ui.control.UIAxes
        UIAxesHidden10                matlab.ui.control.UIAxes
        UIAxesHidden5                 matlab.ui.control.UIAxes
        UIAxesHidden6                 matlab.ui.control.UIAxes
        UIAxesHidden8                 matlab.ui.control.UIAxes
        UIAxesHidden9                 matlab.ui.control.UIAxes
        UIAxesHidden11                matlab.ui.control.UIAxes
        UIAxesHidden12                matlab.ui.control.UIAxes
        UploadButtonHidden4           matlab.ui.control.Button
        UploadButtonHidden5           matlab.ui.control.Button
        UploadButtonHidden6           matlab.ui.control.Button
        UploadButtonHidden7           matlab.ui.control.Button
        UploadButtonHidden8           matlab.ui.control.Button
        UploadButtonHidden9           matlab.ui.control.Button
        UploadButtonHidden10          matlab.ui.control.Button
        UploadButtonHidden11          matlab.ui.control.Button
        UploadButtonHidden12          matlab.ui.control.Button
        EncryptImageButton_2          matlab.ui.control.Button
        MethodSelectionDropDownLabel  matlab.ui.control.Label
        methodSelection               matlab.ui.control.DropDown
        DecryptTab                    matlab.ui.container.Tab
        HomeButton_2                  matlab.ui.control.Button
        UIAxesUserImage_2             matlab.ui.control.UIAxes
        UploadEncryptedImageButton    matlab.ui.control.Button
        SelectNumofSecretsDropDown_2Label  matlab.ui.control.Label
        SelectNumofSecretsDropDown_2  matlab.ui.control.DropDown
        DecryptImageButton            matlab.ui.control.Button
        UIAxesHiddenD1                matlab.ui.control.UIAxes
        UIAxesHiddenD2                matlab.ui.control.UIAxes
        UIAxesHiddenD3                matlab.ui.control.UIAxes
        UIAxesHiddenD4                matlab.ui.control.UIAxes
        UIAxesHiddenD7                matlab.ui.control.UIAxes
        UIAxesHiddenD5                matlab.ui.control.UIAxes
        UIAxesHiddenD6                matlab.ui.control.UIAxes
        UIAxesHiddenD8                matlab.ui.control.UIAxes
        UIAxesHiddenD9                matlab.ui.control.UIAxes
        UIAxesHiddenD10               matlab.ui.control.UIAxes
        UIAxesHiddenD11               matlab.ui.control.UIAxes
        UIAxesHiddenD12               matlab.ui.control.UIAxes
        SelectMethodDropDownLabel     matlab.ui.control.Label
        SelectMethodDropDown          matlab.ui.control.DropDown
        InfoTab                       matlab.ui.container.Tab
        OneMonkeyFreeMonkeyLabel      matlab.ui.control.Label
        TextAreaLabel                 matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        HomeButtonEncrypt_2           matlab.ui.control.Button
    end

        
    properties (Access = private)
        numberSecrets
        userImage
        method % Description
    end

    
    methods (Access = private)
        
        function encryptedImage = encryptImage(app, userImageInput, hidden1User, hidden2User, hidden3User, hidden4User, hidden5User, hidden6User, hidden7User, hidden8User, hidden9User, hidden10User, hidden11User, hidden12User)
        
        numOfHidUser = nargin-2;
        userRedLayer = userImageInput(:,:,1);
        userGreenLayer = userImageInput(:,:,2);
        userBlueLayer = userImageInput(:,:,3);
       
        switch numOfHidUser
            
            case 1 % 1 image Red layer
                encrypted1ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User);
                encryptedImage = cat(3,encrypted1ImageRedLayer,userGreenLayer,userBlueLayer);
            
            case 2 % 2 images Red layer
                encrypted2ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User,hidden2User);
                encryptedImage = cat(3,encrypted2ImageRedLayer,userGreenLayer,userBlueLayer);
            
            case 3 % 3 images Red layer
                encrypted3ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User,hidden2User,hidden3User);
                encryptedImage = cat(3,encrypted3ImageRedLayer,userGreenLayer,userBlueLayer);
            
            case 4 % 4 images Red layer
                encrypted4ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User,hidden2User,hidden3User,hidden4User);
                encryptedImage = cat(3,encrypted4ImageRedLayer,userGreenLayer,userBlueLayer);
            
            case 5 % 4 images Red layer, 1 image Green layer
                encrypted1ImageGreenLayer = encryptImageLayer(userImageInput,'G',hidden5User);
                encrypted4ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User,hidden2User,hidden3User,hidden4User);
                encryptedImage = cat(3,encrypted4ImageRedLayer,encrypted1ImageGreenLayer,userBlueLayer);
            
            case 6 % 4 images Red layer, 2 image Green layer      
                encrypted4ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User,hidden2User,hidden3User,hidden4User);
                encrypted2ImageGreenLayer = encryptImageLayer(userImageInput,'G',hidden5User,hidden6User);
                encryptedImage = cat(3,encrypted4ImageRedLayer,encrypted2ImageGreenLayer,userBlueLayer);
            
            case 7 % 4 images Red layer, 3 image Green layer
                encrypted4ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User,hidden2User,hidden3User,hidden4User);
                encrypted3ImageGreenLayer = encryptImageLayer(userImageInput,'G',hidden5User,hidden6User,hidden7User);
                encryptedImage = cat(3,encrypted4ImageRedLayer,encrypted3ImageGreenLayer,userBlueLayer);
            
            case 8 % 4 images Red layer, 4 image Green layer
                encrypted4ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User,hidden2User,hidden3User,hidden4User);
                encrypted4ImageGreenLayer = encryptImageLayer(userImageInput,'G',hidden5User,hidden6User,hidden7User,hidden8User);
                encryptedImage = cat(3,encrypted4ImageRedLayer,encrypted4ImageGreenLayer,userBlueLayer);
            
            case 9 % 4 images Red layer, 4 image Green layer, 1 image Blue layer
                encrypted4ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User,hidden2User,hidden3User,hidden4User);
                encrypted4ImageGreenLayer = encryptImageLayer(userImageInput,'G',hidden5User,hidden6User,hidden7User,hidden8User);
                encrypted1ImageBlueLayer = encryptImageLayer(userImageInput,'B',hidden9User);
                encryptedImage = uint8(cat(3,encrypted4ImageRedLayer,encrypted4ImageGreenLayer,encrypted1ImageBlueLayer));
            
            case 10 % 4 images Red layer, 4 image Green layer, 2 image Blue layer
                encrypted4ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User,hidden2User,hidden3User,hidden4User);
                encrypted4ImageGreenLayer = encryptImageLayer(userImageInput,'G',hidden5User,hidden6User,hidden7User,hidden8User);
                encrypted2ImageBlueLayer = encryptImageLayer(userImageInput,'B',hidden9User,hidden10User);
                encryptedImage = uint8(cat(3,encrypted4ImageRedLayer,encrypted4ImageGreenLayer,encrypted2ImageBlueLayer));
            
            case 11 % 4 images Red layer, 4 image Green layer, 3 image Blue layer
                encrypted4ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User,hidden2User,hidden3User,hidden4User);
                encrypted4ImageGreenLayer = encryptImageLayer(userImageInput,'G',hidden5User,hidden6User,hidden7User,hidden8User);
                encrypted3ImageBlueLayer = encryptImageLayer(userImageInput,'B',hidden9User,hidden10User,hidden11User);
                encryptedImage = uint8(cat(3,encrypted4ImageRedLayer,encrypted4ImageGreenLayer,encrypted3ImageBlueLayer));
                
            case 12 % 4 images Red layer, 4 image Blue layer, 4 image Green layer
                encrypted4ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User,hidden2User,hidden3User,hidden4User);
                encrypted4ImageGreenLayer = encryptImageLayer(userImageInput,'G',hidden5User,hidden6User,hidden7User,hidden8User);
                encrypted4ImageBlueLayer = encryptImageLayer(userImageInput,'B',hidden9User,hidden10User,hidden11User,hidden12User);
                encryptedImage = uint8(cat(3,encrypted4ImageRedLayer,encrypted4ImageGreenLayer,encrypted4ImageBlueLayer));
        end


        figure('Name','Save your encrypted image.')   
        imshow(encryptedImage,'Border','tight');
        saveas(gcf,'Encrypted_Image.png');
        
        
        function encryptedImageLayer = encryptImageLayer(userImage, layer, hidden1, hidden2, hidden3, hidden4);
        
        
        %% ----------GET NUMBER OF INPUTS----------
        numOfInputs = nargin;
        
        
        %% ----------FLATTEN THE SECRET IMAGES----------
        switch numOfInputs
            case 3
                hidden01 = flattenSecret(hidden1);
            case 4
                hidden01 = flattenSecret(hidden1);
                hidden02 = flattenSecret(hidden2);
            case 5
                hidden01 = flattenSecret(hidden1);
                hidden02 = flattenSecret(hidden2);
                hidden03 = flattenSecret(hidden3);
            case 6
                hidden01 = flattenSecret(hidden1);
                hidden02 = flattenSecret(hidden2);
                hidden03 = flattenSecret(hidden3);
                hidden04 = flattenSecret(hidden4);
        end
        
        %% ---------- DECLARE THE RGB LAYERS----------
        redLayerUserImage = userImage(:,:,1);
        greenLayerUserImage = userImage(:,:,2);
        blueLayerUserImage = userImage(:,:,3);
        
        
        %% ----------BINARY ENCRYPTION TABLE ------------------            
        RGBvalues = 0:255; % RGB values
        switch numOfInputs
            case 3 % 1 image per layer
                 binaryTable = dec2bin(0:(2^1-1)) - '0';
                 startValue1image = 1;
                 position1image = 2;
        
                 for i = 1:2
                    for j = startValue1image:2:256
                        binaryTable(i,position1image) = RGBvalues(j);
                        position1image = position1image + 1;
                    end
                        position1image = 2;
                        startValue1image = startValue1image + 1;
                 end
            %------------------------------------------------------------------     
            case 4 % 2 images per layer
                 binaryTable = dec2bin(0:(2^2-1)) - '0';
                 startValue2image = 1;
                 position2image = 3;
        
                 for i = 1:4
                    for j = startValue2image:4:256
                        binaryTable(i,position2image) = RGBvalues(j);
                        position2image = position2image + 1;
                    end
                        position2image = 3;
                        startValue2image = startValue2image + 1;
                 end
            %------------------------------------------------------------------
            case 5 % 3 images per layer
                 binaryTable = dec2bin(0:(2^3-1)) - '0';
                 startValue3image = 1;
                 position3image = 4;
        
                 for i = 1:8
                    for j = startValue3image:8:256
                        binaryTable(i,position3image) = RGBvalues(j);
                        position3image = position3image + 1;
                    end
                        position3image = 4;
                        startValue3image = startValue3image + 1;
                 end
        
            %------------------------------------------------------------------
            case 6 % 4 images per layer
                 binaryTable = dec2bin(0:(2^4-1)) - '0';
        
                 startValue4image = 1;
                 position4image = 5;
        
                 for i = 1:16
                    for j = startValue4image:16:256
                        binaryTable(i,position4image) = RGBvalues(j);
                        position4image = position4image + 1;
                    end
                        position4image = 5;
                        startValue4image = startValue4image + 1;
                 end 
            end
        
        [xSizeBinaryTable, ySizeBinaryTable] = size(binaryTable);
        
        
        %% ENCRYPT THE ORIGINAL IMAGE
        
        [xSizeUserImage, ySizeUserImage,RGB] = size(userImage);
        [xSizeHid, ySizeHid] = size(hidden01);
        
        encryptedLayerRed = zeros(xSizeHid,ySizeHid);
        encryptedLayerGreen = zeros(xSizeHid,ySizeHid);
        encryptedLayerBlue = zeros(xSizeHid,ySizeHid);
        
        switch numOfInputs
            case 3 % 1 image
                switch layer
                    case 'R' % ENCRYTPTION FOR RED LAYER
                        for i = 1:xSizeUserImage
                            for j = 1:ySizeUserImage
                                encryptedLayerRed(i,j) = findClosestPixel(redLayerUserImage(i,j),hidden01(i,j));
                            end
                        end
        
                    case 'G'
                        for i = 1:xSizeUserImage
                            for j = 1:ySizeUserImage
                                encryptedLayerGreen(i,j) = findClosestPixel(greenLayerUserImage(i,j),hidden01(i,j));
                            end
                        end
        
                    case 'B'
                        for i = 1:xSizeUserImage
                            for j = 1:ySizeUserImage
                                encryptedLayerBlue(i,j) = findClosestPixel(blueLayerUserImage(i,j),hidden01(i,j));
                            end
                        end      
                end


            case 4 % 2 images
                switch layer
                    case 'R' % ENCRYTPTION FOR RED LAYER
                        for i = 1:xSizeUserImage
                            for j = 1:ySizeUserImage
                                encryptedLayerRed(i,j) = findClosestPixel(redLayerUserImage(i,j),hidden01(i,j),hidden02(i,j));
                            end
                        end
                        
                    case 'G'
                        for i = 1:xSizeUserImage
                            for j = 1:ySizeUserImage
                                encryptedLayerGreen(i,j) = findClosestPixel(greenLayerUserImage(i,j),hidden01(i,j),hidden02(i,j));
                            end
                        end
                    case 'B'
                        for i = 1:xSizeUserImage
                            for j = 1:ySizeUserImage
                                encryptedLayerBlue(i,j) = findClosestPixel(blueLayerUserImage(i,j),hidden01(i,j),hidden02(i,j));
                            end
                        end
                end


            case 5 % 3 images
                switch layer
                    case 'R' % ENCRYTPTION FOR RED LAYER
                        for i = 1:xSizeUserImage
                            for j = 1:ySizeUserImage
                                encryptedLayerRed(i,j) = findClosestPixel(redLayerUserImage(i,j),hidden01(i,j),hidden02(i,j),hidden03(i,j));
                            end
                        end
        
                    case 'G'
                        for i = 1:xSizeUserImage
                            for j = 1:ySizeUserImage
                                encryptedLayerGreen(i,j) = findClosestPixel(greenLayerUserImage(i,j),hidden01(i,j),hidden02(i,j),hidden03(i,j));
                            end
                        end
        
        
                    case 'B'
                        for i = 1:xSizeUserImage
                            for j = 1:ySizeUserImage
                                encryptedLayerBlue(i,j) = findClosestPixel(blueLayerUserImage(i,j),hidden01(i,j),hidden02(i,j),hidden03(i,j));
                            end
                        end
                end
        
            case 6 % 4 images
                switch layer
                    case 'R' % ENCRYTPTION FOR RED LAYER
                        for i = 1:xSizeUserImage
                            for j = 1:ySizeUserImage
        
                                encryptedLayerRed(i,j) = findClosestPixel(redLayerUserImage(i,j),hidden01(i,j),hidden02(i,j),hidden03(i,j),hidden04(i,j));
        
                            end
                        end
        
                    case 'G'
                        for i = 1:xSizeUserImage
                            for j = 1:ySizeUserImage
        
                                encryptedLayerGreen(i,j) = findClosestPixel(greenLayerUserImage(i,j),hidden01(i,j),hidden02(i,j),hidden03(i,j),hidden04(i,j));
        
                            end
                        end
        
        
                    case 'B'
                        for i = 1:xSizeUserImage
                            for j = 1:ySizeUserImage
                                encryptedLayerBlue(i,j) = findClosestPixel(blueLayerUserImage(i,j),hidden01(i,j),hidden02(i,j),hidden03(i,j),hidden04(i,j));
        
                            end
                        end
        
                 end
        
        end


        %% -----------OBTAINING THE CLOSEST PIXEL VALUE TO THE BIT VALUE------------
                function closestPixel = findClosestPixel(desiredPixel, bit1,bit2,bit3,bit4)


                rowPixel = 0; % initializing the row in which desired pixel is
        
                % finding the row in which original pixel is
                for k = 1:xSizeBinaryTable
                    switch numOfInputs
                        case 3
                            if bit1 == binaryTable(k,1) % 1 image
                                rowPixel = k;
                            end
                        case 4
                            if bit1 == binaryTable(k,1) & bit2 == binaryTable(k,2) % 2 images
                                rowPixel = k;
                            end
                        case 5
                            if bit1 == binaryTable(k,1) & bit2 == binaryTable(k,2) & bit3 == binaryTable(k,3) % 3 images
                                rowPixel = k;
                            end
                        case 6
                            if bit1 == binaryTable(k,1) & bit2 == binaryTable(k,2) & bit3 == binaryTable(k,3) & bit4 == binaryTable(k,4) % 4 images
                                rowPixel = k;
                            end
                    end
                end
        
        
                % finding closest pixel
                initialMin = desiredPixel;
                closestPixel = 0;
                desiredPixel = double(desiredPixel);
        
                % loop thorough the binary table and find the closest pixel 
                for w = (numOfInputs - 1):ySizeBinaryTable
                    if binaryTable(rowPixel,w) ~= 0
                        if abs(desiredPixel - binaryTable(rowPixel,w)) <= initialMin
                            initialMin = abs(binaryTable(rowPixel,w)-desiredPixel);
                            closestPixel = binaryTable(rowPixel,w);
                        end
                    end
                end
        
                end
        
        % ------------------------ END OF CLOSEST PIXEL FUNCTION --------------------   
      
        switch layer
            case 'R'
                encryptedImageLayer = encryptedLayerRed;
            case 'G' 
                encryptedImageLayer = encryptedLayerGreen;
            case 'B'    
                encryptedImageLayer = encryptedLayerBlue;
        end
    
        end


        function flattenSecretImage = flattenSecret(secretImg)
        % input secretImg
        % input must be grayscale image
        % converts the 256 bit layer into 0 and 1
        
        
        [xSize, ySize] = size(secretImg); % get the size of the input image
        
        flattenSecretImage = zeros(xSize); % create empty matrix for output
        
        threshold = 100; % create cut off threshold
        
        for i = 1:xSize
            for j = 1:ySize
                if secretImg(i,j) >= threshold %
                    flattenSecretImage(i,j) = 1; % set it to black
                else
                    flattenSecretImage(i,j) = 0; % set it to white
                end
            end
        end
        end
        end
        
        function decryptImage(app, encryptedUserImage, numOfImages)

%% -----------LAYERS OF ENCRYPTED IMAGE--------------
redLayerEncrypted = encryptedUserImage(:,:,1);
greenLayerEncrypted = encryptedUserImage(:,:,2);
blueLayerEncrypted = encryptedUserImage(:,:,3);


%% ---------- SWITCH FOR DECRYPTION OF EACH LAYER -------
switch numOfImages
    case '1'
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,1);
        figure('Name', 'These images were embedded in the provided image.')
        subplot(1,1,1);
        imshow((hiddenImageRedLayer));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)

    case '2'
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,2);
        figure('Name', 'These images were embedded in the provided image.')
        subplot(1,2,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(1,2,2);
        imshow(hiddenImageRedLayer(:,:,2));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageRedLayer(:,:,2), 'Parent', app.UIAxesHiddenD2)

    case '3'
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,3);
        figure('Name', 'These images were embedded in the provided image.')
        subplot(1,3,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(1,3,2);
        imshow(hiddenImageRedLayer(:,:,2));
        subplot(1,3,3);
        imshow(hiddenImageRedLayer(:,:,3));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageRedLayer(:,:,2), 'Parent', app.UIAxesHiddenD2)
        imshow(hiddenImageRedLayer(:,:,3), 'Parent', app.UIAxesHiddenD3)

    case '4'
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,4);
        figure('Name', 'These images were embedded in the provided image.')
        subplot(1,4,1);
        imshow(hiddenImageRedLayer(:,:,1), 'Border', 'tight');
        subplot(1,4,2);
        imshow(hiddenImageRedLayer(:,:,2), 'Border', 'tight');
        subplot(1,4,3);
        imshow(hiddenImageRedLayer(:,:,3), 'Border', 'tight');
        subplot(1,4,4);
        imshow(hiddenImageRedLayer(:,:,4), 'Border', 'tight');
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageRedLayer(:,:,2), 'Parent', app.UIAxesHiddenD2)
        imshow(hiddenImageRedLayer(:,:,3), 'Parent', app.UIAxesHiddenD3)
        imshow(hiddenImageRedLayer(:,:,4), 'Parent', app.UIAxesHiddenD4)

    case '5'
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,4);
        hiddenImageGreenLayer = encryptedLayer(greenLayerEncrypted,1);
        figure('Name', 'These images were embedded in the provided image.')
        subplot(2, 4,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(2,4,2);
        imshow(hiddenImageRedLayer(:,:,2));
        subplot(2,4,3);
        imshow(hiddenImageRedLayer(:,:,3));
        subplot(2,4,4);
        imshow(hiddenImageRedLayer(:,:,4));
        subplot(2,4,5);
        imshow(hiddenImageGreenLayer(:,:,1));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageRedLayer(:,:,2), 'Parent', app.UIAxesHiddenD2)
        imshow(hiddenImageRedLayer(:,:,3), 'Parent', app.UIAxesHiddenD3)
        imshow(hiddenImageRedLayer(:,:,4), 'Parent', app.UIAxesHiddenD4)
        imshow(hiddenImageGreenLayer(:,:,1), 'Parent', app.UIAxesHiddenD5)

    case '6'
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,4);
        hiddenImageGreenLayer = encryptedLayer(greenLayerEncrypted,2);
        figure('Name', 'These images were embedded in the provided image.')
        subplot(2,4,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(2,4,2);
        imshow(hiddenImageRedLayer(:,:,2));
        subplot(2,4,3);
        imshow(hiddenImageRedLayer(:,:,3));
        subplot(2,4,4);
        imshow(hiddenImageRedLayer(:,:,4));
        subplot(2,4,5);
        imshow(hiddenImageGreenLayer(:,:,1));
        subplot(2,4,6);
        imshow(hiddenImageGreenLayer(:,:,2));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageRedLayer(:,:,2), 'Parent', app.UIAxesHiddenD2)
        imshow(hiddenImageRedLayer(:,:,3), 'Parent', app.UIAxesHiddenD3)
        imshow(hiddenImageRedLayer(:,:,4), 'Parent', app.UIAxesHiddenD4)
        imshow(hiddenImageGreenLayer(:,:,1), 'Parent', app.UIAxesHiddenD5)
        imshow(hiddenImageGreenLayer(:,:,2), 'Parent', app.UIAxesHiddenD6)

    case '7'
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,4);
        hiddenImageGreenLayer = encryptedLayer(greenLayerEncrypted,3);
        figure('Name', 'These images were embedded in the provided image.')
        subplot(2,4,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(2,4,2);
        imshow(hiddenImageRedLayer(:,:,2));
        subplot(2,4,3);
        imshow(hiddenImageRedLayer(:,:,3));
        subplot(2,4,4);
        imshow(hiddenImageRedLayer(:,:,4));
        subplot(2,4,5);
        imshow(hiddenImageGreenLayer(:,:,1));
        subplot(2,4,6);
        imshow(hiddenImageGreenLayer(:,:,2));
        subplot(2,4,7);
        imshow(hiddenImageGreenLayer(:,:,3));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageRedLayer(:,:,2), 'Parent', app.UIAxesHiddenD2)
        imshow(hiddenImageRedLayer(:,:,3), 'Parent', app.UIAxesHiddenD3)
        imshow(hiddenImageRedLayer(:,:,4), 'Parent', app.UIAxesHiddenD4)
        imshow(hiddenImageGreenLayer(:,:,1), 'Parent', app.UIAxesHiddenD5)
        imshow(hiddenImageGreenLayer(:,:,2), 'Parent', app.UIAxesHiddenD6)
        imshow(hiddenImageGreenLayer(:,:,3), 'Parent', app.UIAxesHiddenD7)

    case '8' 
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,4);
        hiddenImageGreenLayer = encryptedLayer(greenLayerEncrypted,4);
        figure('Name', 'These images were embedded in the provided image.')
        subplot(2,4,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(2,4,2);
        imshow(hiddenImageRedLayer(:,:,2));
        subplot(2,4,3);
        imshow(hiddenImageRedLayer(:,:,3));
        subplot(2,4,4);
        imshow(hiddenImageRedLayer(:,:,4));
        subplot(2,4,5);
        imshow(hiddenImageGreenLayer(:,:,1));
        subplot(2,4,6);
        imshow(hiddenImageGreenLayer(:,:,2));
        subplot(2,4,7);
        imshow(hiddenImageGreenLayer(:,:,3));
        subplot(2,4,8);
        imshow(hiddenImageGreenLayer(:,:,4));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageRedLayer(:,:,2), 'Parent', app.UIAxesHiddenD2)
        imshow(hiddenImageRedLayer(:,:,3), 'Parent', app.UIAxesHiddenD3)
        imshow(hiddenImageRedLayer(:,:,4), 'Parent', app.UIAxesHiddenD4)
        imshow(hiddenImageGreenLayer(:,:,1), 'Parent', app.UIAxesHiddenD5)
        imshow(hiddenImageGreenLayer(:,:,2), 'Parent', app.UIAxesHiddenD6)
        imshow(hiddenImageGreenLayer(:,:,3), 'Parent', app.UIAxesHiddenD7)
        imshow(hiddenImageGreenLayer(:,:,4), 'Parent', app.UIAxesHiddenD8)        

    case '9'
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,4);
        hiddenImageGreenLayer = encryptedLayer(greenLayerEncrypted,4);
        hiddenImageBlueLayer = encryptedLayer(blueLayerEncrypted,1);
        figure('Name', 'These images were embedded in the provided image.')
        subplot(3,4,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(3,4,2);
        imshow(hiddenImageRedLayer(:,:,2));
        subplot(3,4,3);
        imshow(hiddenImageRedLayer(:,:,3));
        subplot(3,4,4);
        imshow(hiddenImageRedLayer(:,:,4));
        subplot(3,4,5);
        imshow(hiddenImageGreenLayer(:,:,1));
        subplot(3,4,6);
        imshow(hiddenImageGreenLayer(:,:,2));
        subplot(3,4,7);
        imshow(hiddenImageGreenLayer(:,:,3));
        subplot(3,4,8);
        imshow(hiddenImageGreenLayer(:,:,4));
        subplot(3,4,9);
        imshow(hiddenImageBlueLayer(:,:,1));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageRedLayer(:,:,2), 'Parent', app.UIAxesHiddenD2)
        imshow(hiddenImageRedLayer(:,:,3), 'Parent', app.UIAxesHiddenD3)
        imshow(hiddenImageRedLayer(:,:,4), 'Parent', app.UIAxesHiddenD4)
        imshow(hiddenImageGreenLayer(:,:,1), 'Parent', app.UIAxesHiddenD5)
        imshow(hiddenImageGreenLayer(:,:,2), 'Parent', app.UIAxesHiddenD6)
        imshow(hiddenImageGreenLayer(:,:,3), 'Parent', app.UIAxesHiddenD7)
        imshow(hiddenImageGreenLayer(:,:,4), 'Parent', app.UIAxesHiddenD8)        
        imshow(hiddenImageBlueLayer(:,:,1), 'Parent', app.UIAxesHiddenD9)

    case '10'
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,4);
        hiddenImageGreenLayer = encryptedLayer(greenLayerEncrypted,4);
        hiddenImageBlueLayer = encryptedLayer(blueLayerEncrypted,2);
        figure('Name', 'These images were embedded in the provided image.')
        subplot(3,4,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(3,4,2);
        imshow(hiddenImageRedLayer(:,:,2));
        subplot(3,4,3);
        imshow(hiddenImageRedLayer(:,:,3));
        subplot(3,4,4);
        imshow(hiddenImageRedLayer(:,:,4));
        subplot(3,4,5);
        imshow(hiddenImageGreenLayer(:,:,1));
        subplot(3,4,6);
        imshow(hiddenImageGreenLayer(:,:,2));
        subplot(3,4,7);
        imshow(hiddenImageGreenLayer(:,:,3));
        subplot(3,4,8);
        imshow(hiddenImageGreenLayer(:,:,4));
        subplot(3,4,9);
        imshow(hiddenImageBlueLayer(:,:,1));
        subplot(3,4,10);
        imshow(hiddenImageBlueLayer(:,:,2));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageRedLayer(:,:,2), 'Parent', app.UIAxesHiddenD2)
        imshow(hiddenImageRedLayer(:,:,3), 'Parent', app.UIAxesHiddenD3)
        imshow(hiddenImageRedLayer(:,:,4), 'Parent', app.UIAxesHiddenD4)
        imshow(hiddenImageGreenLayer(:,:,1), 'Parent', app.UIAxesHiddenD5)
        imshow(hiddenImageGreenLayer(:,:,2), 'Parent', app.UIAxesHiddenD6)
        imshow(hiddenImageGreenLayer(:,:,3), 'Parent', app.UIAxesHiddenD7)
        imshow(hiddenImageGreenLayer(:,:,4), 'Parent', app.UIAxesHiddenD8)        
        imshow(hiddenImageBlueLayer(:,:,1), 'Parent', app.UIAxesHiddenD9)
        imshow(hiddenImageBlueLayer(:,:,2), 'Parent', app.UIAxesHiddenD10)

    case '11'
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,4);
        hiddenImageGreenLayer = encryptedLayer(greenLayerEncrypted,4);
        hiddenImageBlueLayer = encryptedLayer(blueLayerEncrypted,3);
        figure('Name', 'These images were embedded in the provided image.')
        subplot(3,4,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(3,4,2);
        imshow(hiddenImageRedLayer(:,:,2));
        subplot(3,4,3);
        imshow(hiddenImageRedLayer(:,:,3));
        subplot(3,4,4);
        imshow(hiddenImageRedLayer(:,:,4));
        subplot(3,4,5);
        imshow(hiddenImageGreenLayer(:,:,1));
        subplot(3,4,6);
        imshow(hiddenImageGreenLayer(:,:,2));
        subplot(3,4,7);
        imshow(hiddenImageGreenLayer(:,:,3));
        subplot(3,4,8);
        imshow(hiddenImageGreenLayer(:,:,4));
        subplot(3,4,9);
        imshow(hiddenImageBlueLayer(:,:,1));
        subplot(3,4,10);
        imshow(hiddenImageBlueLayer(:,:,2));
        subplot(3,4,11);
        imshow(hiddenImageBlueLayer(:,:,3));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageRedLayer(:,:,2), 'Parent', app.UIAxesHiddenD2)
        imshow(hiddenImageRedLayer(:,:,3), 'Parent', app.UIAxesHiddenD3)
        imshow(hiddenImageRedLayer(:,:,4), 'Parent', app.UIAxesHiddenD4)
        imshow(hiddenImageGreenLayer(:,:,1), 'Parent', app.UIAxesHiddenD5)
        imshow(hiddenImageGreenLayer(:,:,2), 'Parent', app.UIAxesHiddenD6)
        imshow(hiddenImageGreenLayer(:,:,3), 'Parent', app.UIAxesHiddenD7)
        imshow(hiddenImageGreenLayer(:,:,4), 'Parent', app.UIAxesHiddenD8)        
        imshow(hiddenImageBlueLayer(:,:,1), 'Parent', app.UIAxesHiddenD9)
        imshow(hiddenImageBlueLayer(:,:,2), 'Parent', app.UIAxesHiddenD10)
        imshow(hiddenImageBlueLayer(:,:,3), 'Parent', app.UIAxesHiddenD11)

    case '12'
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,4);
        hiddenImageGreenLayer = encryptedLayer(greenLayerEncrypted,4);
        hiddenImageBlueLayer = encryptedLayer(blueLayerEncrypted,4);
        figure('Name', 'These images were embedded in the provided image.')
        subplot(3,4,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(3,4,2);
        imshow(hiddenImageRedLayer(:,:,2));
        subplot(3,4,3);
        imshow(hiddenImageRedLayer(:,:,3));
        subplot(3,4,4);
        imshow(hiddenImageRedLayer(:,:,4));
        subplot(3,4,5);
        imshow(hiddenImageGreenLayer(:,:,1));
        subplot(3,4,6);
        imshow(hiddenImageGreenLayer(:,:,2));
        subplot(3,4,7);
        imshow(hiddenImageGreenLayer(:,:,3));
        subplot(3,4,8);
        imshow(hiddenImageGreenLayer(:,:,4));
        subplot(3,4,9);
        imshow(hiddenImageBlueLayer(:,:,1));
        subplot(3,4,10);
        imshow(hiddenImageBlueLayer(:,:,2));
        subplot(3,4,11);
        imshow(hiddenImageBlueLayer(:,:,3));
        subplot(3,4,12);
        imshow(hiddenImageBlueLayer(:,:,4));

        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageRedLayer(:,:,2), 'Parent', app.UIAxesHiddenD2)
        imshow(hiddenImageRedLayer(:,:,3), 'Parent', app.UIAxesHiddenD3)
        imshow(hiddenImageRedLayer(:,:,4), 'Parent', app.UIAxesHiddenD4)
        imshow(hiddenImageGreenLayer(:,:,1), 'Parent', app.UIAxesHiddenD5)
        imshow(hiddenImageGreenLayer(:,:,2), 'Parent', app.UIAxesHiddenD6)
        imshow(hiddenImageGreenLayer(:,:,3), 'Parent', app.UIAxesHiddenD7)
        imshow(hiddenImageGreenLayer(:,:,4), 'Parent', app.UIAxesHiddenD8)        
        imshow(hiddenImageBlueLayer(:,:,1), 'Parent', app.UIAxesHiddenD9)
        imshow(hiddenImageBlueLayer(:,:,2), 'Parent', app.UIAxesHiddenD10)
        imshow(hiddenImageBlueLayer(:,:,3), 'Parent', app.UIAxesHiddenD11)
        imshow(hiddenImageBlueLayer(:,:,4), 'Parent', app.UIAxesHiddenD12)
end
    
        
%% ------------ DECRYPTING EMPTY HIDDEN IMAGES -----

    function decryptedLayer = encryptedLayer(layer,numberOfImagesLayer)
                 
        
        %% ----------BINARY ENCRYPTION TABLE ------------------            
            RGBvalues = 0:255; % RGB values
            switch numberOfImagesLayer
                case 1 % 1 image per layer
                     binaryTable = dec2bin(0:(2^1-1)) - '0';
                     startValue1image = 1;
                     position1image = 2;

                     for i = 1:2
                        for j = startValue1image:2:256
                            binaryTable(i,position1image) = RGBvalues(j);
                            position1image = position1image + 1;
                        end
                            position1image = 2;
                            startValue1image = startValue1image + 1;
                     end
                %------------------------------------------------------------------     
                case 2 % 2 images per layer
                     binaryTable = dec2bin(0:(2^2-1)) - '0';
                     startValue2image = 1;
                     position2image = 3;

                     for i = 1:4
                        for j = startValue2image:4:256
                            binaryTable(i,position2image) = RGBvalues(j);
                            position2image = position2image + 1;
                        end
                            position2image = 3;
                            startValue2image = startValue2image + 1;
                     end
                %------------------------------------------------------------------
                case 3 % 3 images per layer
                     binaryTable = dec2bin(0:(2^3-1)) - '0';
                     startValue3image = 1;
                     position3image = 4;

                     for i = 1:8
                        for j = startValue3image:8:256
                            binaryTable(i,position3image) = RGBvalues(j);
                            position3image = position3image + 1;
                        end
                            position3image = 4;
                            startValue3image = startValue3image + 1;
                     end

                %------------------------------------------------------------------
                case 4 % 4 images per layer
                     binaryTable = dec2bin(0:(2^4-1)) - '0';

                     startValue4image = 1;
                     position4image = 5;

                     for i = 1:16
                        for j = startValue4image:16:256
                            binaryTable(i,position4image) = RGBvalues(j);
                            position4image = position4image + 1;
                        end
                            position4image = 5;
                            startValue4image = startValue4image + 1;
                     end 
            end


            hiddenImages = zeros(400,400,numberOfImagesLayer);

            numberOfBits = numberOfImagesLayer;

            [xSizeUserImage, ySizeUserImage,RGB] = size(encryptedUserImage);

            [xSizeBinaryTable, ySizeBinaryTable] = size(binaryTable);


            for z = 1:xSizeUserImage
                for w = 1:ySizeUserImage
                    for k = (1+numberOfBits):ySizeBinaryTable
                        for q = 1:xSizeBinaryTable
                            if layer(z,w) == binaryTable(q,k)
                                switch numberOfImagesLayer
                                    case 1
                                        hiddenImages(z,w,1) = binaryTable(q,1);
                                        
                                    case 2
                                        hiddenImages(z,w,1) = binaryTable(q,1);
                                        hiddenImages(z,w,2) = binaryTable(q,2);
                                       
                                    case 3
                                        hiddenImages(z,w,1) = binaryTable(q,1);
                                        hiddenImages(z,w,2) = binaryTable(q,2);
                                        hiddenImages(z,w,3) = binaryTable(q,3);
                                        
                                    case 4
                                        hiddenImages(z,w,1) = binaryTable(q,1);
                                        hiddenImages(z,w,2) = binaryTable(q,2);
                                        hiddenImages(z,w,3) = binaryTable(q,3);
                                        hiddenImages(z,w,4) = binaryTable(q,4);
                                end

                            end        
                        end
                    end
                end
            end

            switch numberOfImagesLayer
                case 1
                    decryptedImage1 = hiddenImages(:,:,1);
                    decryptedLayer = decryptedImage1;
                case 2
                    decryptedImage1 = hiddenImages(:,:,1);
                    decryptedImage2 = hiddenImages(:,:,2);
                    decryptedLayer = cat(3,decryptedImage1,decryptedImage2);
                case 3
                    decryptedImage1 = hiddenImages(:,:,1);
                    decrytpedImage2 = hiddenImages(:,:,2);
                    decryptedImage3 = hiddenImages(:,:,3);
                    decryptedLayer = cat(3,decryptedImage1,decrytpedImage2,decryptedImage3);
                case 4
                    decryptedImage1 = hiddenImages(:,:,1);
                    decryptedImage2 = hiddenImages(:,:,2);
                    decryptedImage3 = hiddenImages(:,:,3);
                    decryptedImage4 = hiddenImages(:,:,4);
                    decryptedLayer = cat(3,decryptedImage1, decryptedImage2,decryptedImage3,decryptedImage4);
            end
           
    end


        end
        
        function encryptedImage = encryptImageLayer(app, userImageInput, hidden1User, hidden2User, hidden3User, hidden4User, hidden5User, hidden6User, hidden7User, hidden8User, hidden9User, hidden10User, hidden11User, hidden12User)
%tic
%[encryptedImage,redEncrypted,greenEncrypted,blueEncrypted,userRedLayer,userGreenLayer,userBlueLayer]

numOfHidUser = nargin-2;
userRedLayer = userImageInput(:,:,1);
userGreenLayer = userImageInput(:,:,2);
userBlueLayer = userImageInput(:,:,3);

switch numOfHidUser
    
    case 1 % 1 image Red layer
        encrypted1ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User);
        encryptedImage = unit8(cat(3,encrypted1ImageRedLayer,userGreenLayer,userBlueLayer));
    
    case 2 % 1 image Red layer, 1 image Green layer
        encrypted2ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User);
        encrypted2ImageGreenLayer = encryptImageLayer(userImageInput,'G',hidden2User);
        encryptedImage = cat(3,encrypted2ImageRedLayer,encrypted2ImageGreenLayer,userBlueLayer);
    
    case 3 % 1 image Red layer, 1 image Geen layer, 1 image Blue layer
        encrypted3ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User);
        encrypted3ImageGreenLayer = encryptImageLayer(userImageInput,'G',hidden2User);
        encrypted3ImageBlueLayer = encryptImageLayer(userImageInput,'B',hidden3User);
        encryptedImage = uint8(cat(3,encrypted3ImageRedLayer,encrypted3ImageGreenLayer,encrypted3ImageBlueLayer));
    
    case 4 % 2 images Red layer, 1 image Green layer, 1 image Blue layer
        encrypted4ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User,hidden4User);
        encrypted4ImageGreenLayer = encryptImageLayer(userImageInput,'G',hidden2User);
        encrypted4ImageBlueLayer = encryptImageLayer(userImageInput,'B',hidden3User);
        encryptedImage = uint8(cat(3,encrypted4ImageRedLayer,encrypted4ImageGreenLayer,encrypted4ImageBlueLayer));
    
    case 5 % 2 images Red layer, 2 images Green layer, 1 image Blue layer
        encrypted5ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User,hidden4User);
        encrypted5ImageGreenLayer = encryptImageLayer(userImageInput,'G',hidden2User,hidden5User);
        encrypted5ImageBlueLayer = encryptImageLayer(userImageInput,'B',hidden3User);
        encryptedImage = uint8(cat(3,encrypted5ImageRedLayer,encrypted5ImageGreenLayer,encrypted5ImageBlueLayer));
    
    case 6 % 2 images Red layer, 2 images Green layer, 2 images Blue layer     
        encrypted6ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User,hidden4User);
        encrypted6ImageGreenLayer = encryptImageLayer(userImageInput,'G',hidden2User,hidden5User);
        encrypted6ImageBlueLayer = encryptImageLayer(userImageInput,'B',hidden3User,hidden6User);
        encryptedImage = uint8(cat(3,encrypted6ImageRedLayer,encrypted6ImageGreenLayer,encrypted6ImageBlueLayer));
    
    case 7 % 3 images Red layer, 2 image Green layer, 2 images Blue layer
        encrypted7ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User,hidden4User,hidden7User);
        encrypted7ImageGreenLayer = encryptImageLayer(userImageInput,'G',hidden2User,hidden5User);
        encrypted7ImageBlueLayer = encryptImageLayer(userImageInput,'B',hidden3User,hidden6User);
        encryptedImage = uint8(cat(3,encrypted7ImageRedLayer,encrypted7ImageGreenLayer,encrypted7ImageBlueLayer));
        
    case 8 % 3 images Red layer, 3 image Green layer, 2 images Blue layer
        encrypted8ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User,hidden4User,hidden7User);
        encrypted8ImageGreenLayer = encryptImageLayer(userImageInput,'G',hidden2User,hidden5User,hidden8User);
        encrypted8ImageBlueLayer = encryptImageLayer(userImageInput,'B',hidden3User,hidden6User);
        encryptedImage = uint8(cat(3,encrypted8ImageRedLayer,encrypted8ImageGreenLayer,encrypted8ImageBlueLayer));
    
    case 9 % 3 images Red layer, 3 image Green layer, 3 image Blue layer
        encrypted9ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User,hidden4User,hidden7User);
        encrypted9ImageGreenLayer = encryptImageLayer(userImageInput,'G',hidden2User,hidden5User,hidden8User);
        encrypted9ImageBlueLayer = encryptImageLayer(userImageInput,'B',hidden3User,hidden6User,hidden9User);
        encryptedImage = uint8(cat(3,encrypted9ImageRedLayer,encrypted9ImageGreenLayer,encrypted9ImageBlueLayer));
    
    case 10 % 4 images Red layer, 3 image Green layer, 3 image Blue layer
        encrypted10ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User,hidden4User,hidden7User,hidden10User);
        encrypted10ImageGreenLayer = encryptImageLayer(userImageInput,'G',hidden2User,hidden5User,hidden8User);
        encrypted10ImageBlueLayer = encryptImageLayer(userImageInput,'B',hidden3User,hidden6User,hidden9User);
        encryptedImage = uint8(cat(3,encrypted10ImageRedLayer,encrypted10ImageGreenLayer,encrypted10ImageBlueLayer));
    
    case 11 % 4 images Red layer, 4 image Green layer, 3 image Blue layer
        encrypted11ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User,hidden4User,hidden7User,hidden10User);
        encrypted11ImageGreenLayer = encryptImageLayer(userImageInput,'G',hidden2User,hidden5User,hidden8User,hidden11User);
        encrypted11ImageBlueLayer = encryptImageLayer(userImageInput,'B',hidden3User,hidden6User,hidden9User);
        encryptedImage = uint8(cat(3,encrypted11ImageRedLayer,encrypted11ImageGreenLayer,encrypted11ImageBlueLayer));
        
    case 12 % 4 images Red layer, 4 image Blue layer, 4 image Green layer
        encrypted12ImageRedLayer = encryptImageLayer(userImageInput,'R',hidden1User,hidden4User,hidden7User,hidden10User);
        encrypted12ImageGreenLayer = encryptImageLayer(userImageInput,'G',hidden2User,hidden5User,hidden8User,hidden11User);
        encrypted12ImageBlueLayer = encryptImageLayer(userImageInput,'B',hidden3User,hidden6User,hidden9User,hidden12User);
        encryptedImage = uint8(cat(3,encrypted12ImageRedLayer,encrypted12ImageGreenLayer,encrypted12ImageBlueLayer));
end

        figure('Name','Save your encrypted image.')   
        imshow(encryptedImage, 'Border', 'tight');
        saveas(gcf,'Encrypted_Image.png');


function encryptedImageLayer = encryptImageLayer(userImage, layer, hidden1, hidden2, hidden3, hidden4);


%% ----------GET NUMBER OF INPUTS----------
numOfInputs = nargin;


%% ----------FLATTEN THE SECRET IMAGES----------
switch numOfInputs
    case 3
        hidden01 = flattenSecret(hidden1);
    case 4
        hidden01 = flattenSecret(hidden1);
        hidden02 = flattenSecret(hidden2);
    case 5
        hidden01 = flattenSecret(hidden1);
        hidden02 = flattenSecret(hidden2);
        hidden03 = flattenSecret(hidden3);
    case 6
        hidden01 = flattenSecret(hidden1);
        hidden02 = flattenSecret(hidden2);
        hidden03 = flattenSecret(hidden3);
        hidden04 = flattenSecret(hidden4);
end

%% ---------- DECLARE THE RGB LAYERS----------
redLayerUserImage = userImage(:,:,1);
greenLayerUserImage = userImage(:,:,2);
blueLayerUserImage = userImage(:,:,3);


%% ----------BINARY ENCRYPTION TABLE ------------------            
RGBvalues = 0:255; % RGB values
switch numOfInputs
    case 3 % 1 image per layer
         binaryTable = dec2bin(0:(2^1-1)) - '0';
         startValue1image = 1;
         position1image = 2;

         for i = 1:2
            for j = startValue1image:2:256
                binaryTable(i,position1image) = RGBvalues(j);
                position1image = position1image + 1;
            end
                position1image = 2;
                startValue1image = startValue1image + 1;
         end
    %------------------------------------------------------------------     
    case 4 % 2 images per layer
         binaryTable = dec2bin(0:(2^2-1)) - '0';
         startValue2image = 1;
         position2image = 3;

         for i = 1:4
            for j = startValue2image:4:256
                binaryTable(i,position2image) = RGBvalues(j);
                position2image = position2image + 1;
            end
                position2image = 3;
                startValue2image = startValue2image + 1;
         end
    %------------------------------------------------------------------
    case 5 % 3 images per layer
         binaryTable = dec2bin(0:(2^3-1)) - '0';
         startValue3image = 1;
         position3image = 4;

         for i = 1:8
            for j = startValue3image:8:256
                binaryTable(i,position3image) = RGBvalues(j);
                position3image = position3image + 1;
            end
                position3image = 4;
                startValue3image = startValue3image + 1;
         end

    %------------------------------------------------------------------
    case 6 % 4 images per layer
         binaryTable = dec2bin(0:(2^4-1)) - '0';

         startValue4image = 1;
         position4image = 5;

         for i = 1:16
            for j = startValue4image:16:256
                binaryTable(i,position4image) = RGBvalues(j);
                position4image = position4image + 1;
            end
                position4image = 5;
                startValue4image = startValue4image + 1;
         end 
end

[xSizeBinaryTable, ySizeBinaryTable] = size(binaryTable);


%% ENCRYPT THE ORIGINAL IMAGE

[xSizeUserImage, ySizeUserImage,RGB] = size(userImage);
[xSizeHid, ySizeHid] = size(hidden01);

encryptedLayerRed = zeros(xSizeHid,ySizeHid);
encryptedLayerGreen = zeros(xSizeHid,ySizeHid);
encryptedLayerBlue = zeros(xSizeHid,ySizeHid);

switch numOfInputs
    case 3 % 1 image
        switch layer
            case 'R' % ENCRYTPTION FOR RED LAYER
                for i = 1:xSizeUserImage
                    for j = 1:ySizeUserImage
                        encryptedLayerRed(i,j) = findClosestPixel(redLayerUserImage(i,j),hidden01(i,j));
                    end
                end

            case 'G'
                for i = 1:xSizeUserImage
                    for j = 1:ySizeUserImage
                        encryptedLayerGreen(i,j) = findClosestPixel(greenLayerUserImage(i,j),hidden01(i,j));
                    end
                end

            case 'B'
                for i = 1:xSizeUserImage
                    for j = 1:ySizeUserImage
                        encryptedLayerBlue(i,j) = findClosestPixel(blueLayerUserImage(i,j),hidden01(i,j));
                    end
                end      
        end


    case 4 % 2 images
        switch layer
            case 'R' % ENCRYTPTION FOR RED LAYER
                for i = 1:xSizeUserImage
                    for j = 1:ySizeUserImage
                        encryptedLayerRed(i,j) = findClosestPixel(redLayerUserImage(i,j),hidden01(i,j),hidden02(i,j));
                    end
                end
                
            case 'G'
                for i = 1:xSizeUserImage
                    for j = 1:ySizeUserImage
                        encryptedLayerGreen(i,j) = findClosestPixel(greenLayerUserImage(i,j),hidden01(i,j),hidden02(i,j));
                    end
                end
            case 'B'
                for i = 1:xSizeUserImage
                    for j = 1:ySizeUserImage
                        encryptedLayerBlue(i,j) = findClosestPixel(blueLayerUserImage(i,j),hidden01(i,j),hidden02(i,j));
                    end
                end
        end


    case 5 % 3 images
        switch layer
            case 'R' % ENCRYTPTION FOR RED LAYER
                for i = 1:xSizeUserImage
                    for j = 1:ySizeUserImage
                        encryptedLayerRed(i,j) = findClosestPixel(redLayerUserImage(i,j),hidden01(i,j),hidden02(i,j),hidden03(i,j));
                    end
                end

            case 'G'
                for i = 1:xSizeUserImage
                    for j = 1:ySizeUserImage
                        encryptedLayerGreen(i,j) = findClosestPixel(greenLayerUserImage(i,j),hidden01(i,j),hidden02(i,j),hidden03(i,j));
                    end
                end


            case 'B'
                for i = 1:xSizeUserImage
                    for j = 1:ySizeUserImage
                        encryptedLayerBlue(i,j) = findClosestPixel(blueLayerUserImage(i,j),hidden01(i,j),hidden02(i,j),hidden03(i,j));
                    end
                end
        end

    case 6 % 4 images
        switch layer
            case 'R' % ENCRYTPTION FOR RED LAYER
                for i = 1:xSizeUserImage
                    for j = 1:ySizeUserImage

                        encryptedLayerRed(i,j) = findClosestPixel(redLayerUserImage(i,j),hidden01(i,j),hidden02(i,j),hidden03(i,j),hidden04(i,j));

                    end
                end

            case 'G'
                for i = 1:xSizeUserImage
                    for j = 1:ySizeUserImage

                        encryptedLayerGreen(i,j) = findClosestPixel(greenLayerUserImage(i,j),hidden01(i,j),hidden02(i,j),hidden03(i,j),hidden04(i,j));

                    end
                end


            case 'B'
                for i = 1:xSizeUserImage
                    for j = 1:ySizeUserImage
                        encryptedLayerBlue(i,j) = findClosestPixel(blueLayerUserImage(i,j),hidden01(i,j),hidden02(i,j),hidden03(i,j),hidden04(i,j));

                    end
                end

         end

end


%% -----------OBTAINING THE CLOSEST PIXEL VALUE TO THE BIT VALUE------------
        function closestPixel = findClosestPixel(desiredPixel, bit1,bit2,bit3,bit4)


        rowPixel = 0; % initializing the row in which desired pixel is

        % finding the row in which original pixel is
        for k = 1:xSizeBinaryTable
            switch numOfInputs
                case 3
                    if bit1 == binaryTable(k,1) % 1 image
                        rowPixel = k;
                    end
                case 4
                    if bit1 == binaryTable(k,1) & bit2 == binaryTable(k,2) % 2 images
                        rowPixel = k;
                    end
                case 5
                    if bit1 == binaryTable(k,1) & bit2 == binaryTable(k,2) & bit3 == binaryTable(k,3) % 3 images
                        rowPixel = k;
                    end
                case 6
                    if bit1 == binaryTable(k,1) & bit2 == binaryTable(k,2) & bit3 == binaryTable(k,3) & bit4 == binaryTable(k,4) % 4 images
                        rowPixel = k;
                    end
            end
        end


        % finding closest pixel
        initialMin = desiredPixel;
        closestPixel = 0;
        desiredPixel = double(desiredPixel);

        % loop thorough the binary table and find the closest pixel 
        for w = (numOfInputs - 1):ySizeBinaryTable
            if binaryTable(rowPixel,w) ~= 0
                if abs(desiredPixel - binaryTable(rowPixel,w)) <= initialMin
                    initialMin = abs(binaryTable(rowPixel,w)-desiredPixel);
                    closestPixel = binaryTable(rowPixel,w);
                end
            end
        end

        end

% ------------------------ END OF CLOSEST PIXEL FUNCTION --------------------   


switch layer
    case 'R'
        encryptedImageLayer = encryptedLayerRed;
    case 'G' 
        encryptedImageLayer = encryptedLayerGreen;
    case 'B'    
        encryptedImageLayer = encryptedLayerBlue;
end


end

function flattenSecretImage = flattenSecret(secretImg)
% input secretImg
% input must be grayscale image
% converts the 256 bit layer into 0 and 1


[xSize, ySize] = size(secretImg); % get the size of the input image

flattenSecretImage = zeros(xSize); % create empty matrix for output

threshold = 100; % create cut off threshold

for i = 1:xSize
    for j = 1:ySize
        if secretImg(i,j) >= threshold %
            flattenSecretImage(i,j) = 1; % set it to black
        else
            flattenSecretImage(i,j) = 0; % set it to white
        end
    end
end
end
        end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
    
    function  decryptImageLayer(app, encryptedImage, numOfImages)

%% -----------LAYERS OF ENCRYPTED IMAGE--------------
redLayerEncrypted = encryptedImage(:,:,1);
greenLayerEncrypted = encryptedImage(:,:,2);
blueLayerEncrypted = encryptedImage(:,:,3);


%% ---------- SWITCH FOR DECRYPTION OF EACH LAYER -------
switch numOfImages
    case '1'
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,1);
        subplot(1,1,1);
        imshow((hiddenImageRedLayer));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)

    case '2'
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,1);
        hiddenImageGreenLayer = encryptedLayer(greenLayerEncrypted,1);
        
        subplot(2,1,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(2,1,2);
        imshow(hiddenImageGreenLayer(:,:,1));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageGreenLayer(:,:,1), 'Parent', app.UIAxesHiddenD2)

    case '3' % 1R, 1G, 1B
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,1);
        hiddenImageGreenLayer = encryptedLayer(greenLayerEncrypted,1);
        hiddenImageBlueLayer = encryptedLayer(blueLayerEncrypted,1);
        subplot(3,1,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(3,1,2);
        imshow(hiddenImageGreenLayer(:,:,1));
        subplot(3,1,3);
        imshow(hiddenImageBlueLayer(:,:,1));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageGreenLayer(:,:,1), 'Parent', app.UIAxesHiddenD2)
        imshow(hiddenImageBlueLayer(:,:,1), 'Parent', app.UIAxesHiddenD3)


    case '4' % 2R, 1G, 1B
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,2);
        hiddenImageGreenLayer = encryptedLayer(greenLayerEncrypted,1);
        hiddenImageBlueLayer = encryptedLayer(blueLayerEncrypted,1);
        subplot(4,1,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(4,1,2);
        imshow(hiddenImageRedLayer(:,:,2));
        subplot(4,1,3);
        imshow(hiddenImageGreenLayer(:,:,1));
        subplot(4,1,4);
        imshow(hiddenImageBlueLayer(:,:,1));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageGreenLayer(:,:,1), 'Parent', app.UIAxesHiddenD2)
        imshow(hiddenImageBlueLayer(:,:,1), 'Parent', app.UIAxesHiddenD3)
        imshow(hiddenImageRedLayer(:,:,2), 'Parent', app.UIAxesHiddenD4)


    case '5' % 2R, 2G, 1B
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,2);
        hiddenImageGreenLayer = encryptedLayer(greenLayerEncrypted,2);
        hiddenImageBlueLayer = encryptedLayer(blueLayerEncrypted,1);
        subplot(4,2,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(4,2,2);
        imshow(hiddenImageRedLayer(:,:,2));
        subplot(4,2,3);
        imshow(hiddenImageGreenLayer(:,:,1));
        subplot(4,2,4);
        imshow(hiddenImageGreenLayer(:,:,2));
        subplot(4,2,5);
        imshow(hiddenImageBlueLayer(:,:,1));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageGreenLayer(:,:,1), 'Parent', app.UIAxesHiddenD2)
        imshow(hiddenImageBlueLayer(:,:,1), 'Parent', app.UIAxesHiddenD3)
        imshow(hiddenImageRedLayer(:,:,2), 'Parent', app.UIAxesHiddenD4)
        imshow(hiddenImageGreenLayer(:,:,2), 'Parent', app.UIAxesHiddenD5)

    case '6' % 2R, 2G, 2B
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,2);
        hiddenImageGreenLayer = encryptedLayer(greenLayerEncrypted,2);
        hiddenImageBlueLayer = encryptedLayer(blueLayerEncrypted,2);
        subplot(4,2,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(4,2,2);
        imshow(hiddenImageRedLayer(:,:,2));
        subplot(4,2,3);
        imshow(hiddenImageGreenLayer(:,:,1));
        subplot(4,2,4);
        imshow(hiddenImageGreenLayer(:,:,2));
        subplot(4,2,5);
        imshow(hiddenImageBlueLayer(:,:,1));
        subplot(4,2,6);
        imshow(hiddenImageBlueLayer(:,:,2));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageGreenLayer(:,:,1), 'Parent', app.UIAxesHiddenD2)
        imshow(hiddenImageBlueLayer(:,:,1), 'Parent', app.UIAxesHiddenD3)
        imshow(hiddenImageRedLayer(:,:,2), 'Parent', app.UIAxesHiddenD4)
        imshow(hiddenImageGreenLayer(:,:,2), 'Parent', app.UIAxesHiddenD5)
        imshow(hiddenImageBlueLayer(:,:,2), 'Parent', app.UIAxesHiddenD6)

    case '7' % 3R, 2G, 2B
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,3);
        hiddenImageGreenLayer = encryptedLayer(greenLayerEncrypted,2);
        hiddenImageBlueLayer = encryptedLayer(blueLayerEncrypted,2);
        subplot(4,2,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(4,2,2);
        imshow(hiddenImageRedLayer(:,:,2));
        subplot(4,2,3);
        imshow(hiddenImageRedLayer(:,:,3));
        subplot(4,2,4);
        imshow(hiddenImageGreenLayer(:,:,1));
        subplot(4,2,5);
        imshow(hiddenImageGreenLayer(:,:,2));
        subplot(4,2,6);
        imshow(hiddenImageBlueLayer(:,:,1));
        subplot(4,2,7);
        imshow(hiddenImageBlueLayer(:,:,2));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageGreenLayer(:,:,1), 'Parent', app.UIAxesHiddenD2)
        imshow(hiddenImageBlueLayer(:,:,1), 'Parent', app.UIAxesHiddenD3)
        imshow(hiddenImageRedLayer(:,:,2), 'Parent', app.UIAxesHiddenD4)
        imshow(hiddenImageGreenLayer(:,:,2), 'Parent', app.UIAxesHiddenD5)
        imshow(hiddenImageBlueLayer(:,:,2), 'Parent', app.UIAxesHiddenD6)
        imshow(hiddenImageRedLayer(:,:,3), 'Parent', app.UIAxesHiddenD7)
        

    case '8' % 3R, 3G, 2B
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,3);
        hiddenImageGreenLayer = encryptedLayer(greenLayerEncrypted,3);
        hiddenImageBlueLayer = encryptedLayer(blueLayerEncrypted,2);
        subplot(4,2,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(4,2,2);
        imshow(hiddenImageRedLayer(:,:,2));
        subplot(4,2,3);
        imshow(hiddenImageRedLayer(:,:,3));
        subplot(4,2,4);
        imshow(hiddenImageGreenLayer(:,:,1));
        subplot(4,2,5);
        imshow(hiddenImageGreenLayer(:,:,2));
        subplot(4,2,6);
        imshow(hiddenImageGreenLayer(:,:,3));
        subplot(4,2,7);
        imshow(hiddenImageBlueLayer(:,:,1));
        subplot(4,2,8);
        imshow(hiddenImageBlueLayer(:,:,2));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageGreenLayer(:,:,1), 'Parent', app.UIAxesHiddenD2)
        imshow(hiddenImageBlueLayer(:,:,1), 'Parent', app.UIAxesHiddenD3)
        imshow(hiddenImageRedLayer(:,:,2), 'Parent', app.UIAxesHiddenD4)
        imshow(hiddenImageGreenLayer(:,:,2), 'Parent', app.UIAxesHiddenD5)
        imshow(hiddenImageBlueLayer(:,:,2), 'Parent', app.UIAxesHiddenD6)
        imshow(hiddenImageRedLayer(:,:,3), 'Parent', app.UIAxesHiddenD7)
        imshow(hiddenImageGreenLayer(:,:,3), 'Parent', app.UIAxesHiddenD8)      

    case '9' % 3R, 3G, 3B
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,3);
        hiddenImageGreenLayer = encryptedLayer(greenLayerEncrypted,3);
        hiddenImageBlueLayer = encryptedLayer(blueLayerEncrypted,3);
        subplot(4,3,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(4,3,2);
        imshow(hiddenImageRedLayer(:,:,2));
        subplot(4,3,3);
        imshow(hiddenImageRedLayer(:,:,3));
        subplot(4,3,4);
        imshow(hiddenImageGreenLayer(:,:,1));
        subplot(4,3,5);
        imshow(hiddenImageGreenLayer(:,:,2));
        subplot(4,3,6);
        imshow(hiddenImageGreenLayer(:,:,3));
        subplot(4,3,7);
        imshow(hiddenImageBlueLayer(:,:,1));
        subplot(4,3,8);
        imshow(hiddenImageBlueLayer(:,:,2));
        subplot(4,3,9);
        imshow(hiddenImageBlueLayer(:,:,3));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageGreenLayer(:,:,1), 'Parent', app.UIAxesHiddenD2)
        imshow(hiddenImageBlueLayer(:,:,1), 'Parent', app.UIAxesHiddenD3)
        imshow(hiddenImageRedLayer(:,:,2), 'Parent', app.UIAxesHiddenD4)
        imshow(hiddenImageGreenLayer(:,:,2), 'Parent', app.UIAxesHiddenD5)
        imshow(hiddenImageBlueLayer(:,:,2), 'Parent', app.UIAxesHiddenD6)
        imshow(hiddenImageRedLayer(:,:,3), 'Parent', app.UIAxesHiddenD7)
        imshow(hiddenImageGreenLayer(:,:,3), 'Parent', app.UIAxesHiddenD8)
        imshow(hiddenImageBlueLayer(:,:,3), 'Parent', app.UIAxesHiddenD9)

    case '10' % 4R, 3G, 3B
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,4);
        hiddenImageGreenLayer = encryptedLayer(greenLayerEncrypted,3);
        hiddenImageBlueLayer = encryptedLayer(blueLayerEncrypted,3);
        subplot(4,3,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(4,3,2);
        imshow(hiddenImageRedLayer(:,:,2));
        subplot(4,3,3);
        imshow(hiddenImageRedLayer(:,:,3));
        subplot(4,3,4);
        imshow(hiddenImageRedLayer(:,:,4));
        subplot(4,3,5);
        imshow(hiddenImageGreenLayer(:,:,1));
        subplot(4,3,6);
        imshow(hiddenImageGreenLayer(:,:,2));
        subplot(4,3,7);
        imshow(hiddenImageGreenLayer(:,:,3));
        subplot(4,3,8);
        imshow(hiddenImageBlueLayer(:,:,1));
        subplot(4,3,9);
        imshow(hiddenImageBlueLayer(:,:,2));
        subplot(4,3,10);
        imshow(hiddenImageBlueLayer(:,:,3));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageGreenLayer(:,:,1), 'Parent', app.UIAxesHiddenD2)
        imshow(hiddenImageBlueLayer(:,:,1), 'Parent', app.UIAxesHiddenD3)
        imshow(hiddenImageRedLayer(:,:,2), 'Parent', app.UIAxesHiddenD4)
        imshow(hiddenImageGreenLayer(:,:,2), 'Parent', app.UIAxesHiddenD5)
        imshow(hiddenImageBlueLayer(:,:,2), 'Parent', app.UIAxesHiddenD6)
        imshow(hiddenImageRedLayer(:,:,3), 'Parent', app.UIAxesHiddenD7)
        imshow(hiddenImageGreenLayer(:,:,3), 'Parent', app.UIAxesHiddenD8)
        imshow(hiddenImageBlueLayer(:,:,3), 'Parent', app.UIAxesHiddenD9)
        imshow(hiddenImageRedLayer(:,:,4), 'Parent', app.UIAxesHiddenD10)

    case '11' % 4R, 4G, 3B
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,4);
        hiddenImageGreenLayer = encryptedLayer(greenLayerEncrypted,4);
        hiddenImageBlueLayer = encryptedLayer(blueLayerEncrypted,3);
         subplot(4,3,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(4,3,2);
        imshow(hiddenImageRedLayer(:,:,2));
        subplot(4,3,3);
        imshow(hiddenImageRedLayer(:,:,3));
        subplot(4,3,4);
        imshow(hiddenImageRedLayer(:,:,4));
        subplot(4,3,5);
        imshow(hiddenImageGreenLayer(:,:,1));
        subplot(4,3,6);
        imshow(hiddenImageGreenLayer(:,:,2));
        subplot(4,3,7);
        imshow(hiddenImageGreenLayer(:,:,3));
        subplot(4,3,8);
        imshow(hiddenImageGreenLayer(:,:,4));
        subplot(4,3,9);
        imshow(hiddenImageBlueLayer(:,:,1));
        subplot(4,3,10);
        imshow(hiddenImageBlueLayer(:,:,2));
        subplot(4,3,11);
        imshow(hiddenImageBlueLayer(:,:,3));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageGreenLayer(:,:,1), 'Parent', app.UIAxesHiddenD2)
        imshow(hiddenImageBlueLayer(:,:,1), 'Parent', app.UIAxesHiddenD3)
        imshow(hiddenImageRedLayer(:,:,2), 'Parent', app.UIAxesHiddenD4)
        imshow(hiddenImageGreenLayer(:,:,2), 'Parent', app.UIAxesHiddenD5)
        imshow(hiddenImageBlueLayer(:,:,2), 'Parent', app.UIAxesHiddenD6)
        imshow(hiddenImageRedLayer(:,:,3), 'Parent', app.UIAxesHiddenD7)
        imshow(hiddenImageGreenLayer(:,:,3), 'Parent', app.UIAxesHiddenD8)
        imshow(hiddenImageBlueLayer(:,:,3), 'Parent', app.UIAxesHiddenD9)
        imshow(hiddenImageRedLayer(:,:,4), 'Parent', app.UIAxesHiddenD10)
        imshow(hiddenImageGreenLayer(:,:,4), 'Parent', app.UIAxesHiddenD11)        
        
    case '12' 
        hiddenImageRedLayer = encryptedLayer(redLayerEncrypted,4);
        hiddenImageGreenLayer = encryptedLayer(greenLayerEncrypted,4);
        hiddenImageBlueLayer = encryptedLayer(blueLayerEncrypted,4);
         subplot(4,3,1);
        imshow(hiddenImageRedLayer(:,:,1));
        subplot(4,3,2);
        imshow(hiddenImageRedLayer(:,:,2));
        subplot(4,3,3);
        imshow(hiddenImageRedLayer(:,:,3));
        subplot(4,3,4);
        imshow(hiddenImageRedLayer(:,:,4));
        subplot(4,3,5);
        imshow(hiddenImageGreenLayer(:,:,1));
        subplot(4,3,6);
        imshow(hiddenImageGreenLayer(:,:,2));
        subplot(4,3,7);
        imshow(hiddenImageGreenLayer(:,:,3));
        subplot(4,3,8);
        imshow(hiddenImageGreenLayer(:,:,4));
        subplot(4,3,9);
        imshow(hiddenImageBlueLayer(:,:,1));
        subplot(4,3,10);
        imshow(hiddenImageBlueLayer(:,:,2));
        subplot(4,3,11);
        imshow(hiddenImageBlueLayer(:,:,3));
        subplot(4,3,12);
        imshow(hiddenImageBlueLayer(:,:,4));
        
        imshow(hiddenImageRedLayer(:,:,1), 'Parent', app.UIAxesHiddenD1)
        imshow(hiddenImageGreenLayer(:,:,1), 'Parent', app.UIAxesHiddenD2)
        imshow(hiddenImageBlueLayer(:,:,1), 'Parent', app.UIAxesHiddenD3)
        imshow(hiddenImageRedLayer(:,:,2), 'Parent', app.UIAxesHiddenD4)
        imshow(hiddenImageGreenLayer(:,:,2), 'Parent', app.UIAxesHiddenD5)
        imshow(hiddenImageBlueLayer(:,:,2), 'Parent', app.UIAxesHiddenD6)
        imshow(hiddenImageRedLayer(:,:,3), 'Parent', app.UIAxesHiddenD7)
        imshow(hiddenImageGreenLayer(:,:,3), 'Parent', app.UIAxesHiddenD8)
        imshow(hiddenImageBlueLayer(:,:,3), 'Parent', app.UIAxesHiddenD9)
        imshow(hiddenImageRedLayer(:,:,4), 'Parent', app.UIAxesHiddenD10)
        imshow(hiddenImageGreenLayer(:,:,4), 'Parent', app.UIAxesHiddenD11)        
        imshow(hiddenImageBlueLayer(:,:,4), 'Parent', app.UIAxesHiddenD12)

end
    

%% ------------ DECRYPTING EMPTY HIDDEN IMAGES -----

    function decryptedLayer = encryptedLayer(layer,numberOfImagesLayer)
                 
        
        %% ----------BINARY ENCRYPTION TABLE ------------------            
            RGBvalues = 0:255; % RGB values
            switch numberOfImagesLayer
                case 1 % 1 image per layer
                     binaryTable = dec2bin(0:(2^1-1)) - '0';
                     startValue1image = 1;
                     position1image = 2;

                     for i = 1:2
                        for j = startValue1image:2:256
                            binaryTable(i,position1image) = RGBvalues(j);
                            position1image = position1image + 1;
                        end
                            position1image = 2;
                            startValue1image = startValue1image + 1;
                     end
                %------------------------------------------------------------------     
                case 2 % 2 images per layer
                     binaryTable = dec2bin(0:(2^2-1)) - '0';
                     startValue2image = 1;
                     position2image = 3;

                     for i = 1:4
                        for j = startValue2image:4:256
                            binaryTable(i,position2image) = RGBvalues(j);
                            position2image = position2image + 1;
                        end
                            position2image = 3;
                            startValue2image = startValue2image + 1;
                     end
                %------------------------------------------------------------------
                case 3 % 3 images per layer
                     binaryTable = dec2bin(0:(2^3-1)) - '0';
                     startValue3image = 1;
                     position3image = 4;

                     for i = 1:8
                        for j = startValue3image:8:256
                            binaryTable(i,position3image) = RGBvalues(j);
                            position3image = position3image + 1;
                        end
                            position3image = 4;
                            startValue3image = startValue3image + 1;
                     end

                %------------------------------------------------------------------
                case 4 % 4 images per layer
                     binaryTable = dec2bin(0:(2^4-1)) - '0';

                     startValue4image = 1;
                     position4image = 5;

                     for i = 1:16
                        for j = startValue4image:16:256
                            binaryTable(i,position4image) = RGBvalues(j);
                            position4image = position4image + 1;
                        end
                            position4image = 5;
                            startValue4image = startValue4image + 1;
                     end 
            end


            hiddenImages = zeros(400,400,numberOfImagesLayer);

            numberOfBits = numberOfImagesLayer;

            [xSizeUserImage, ySizeUserImage,RGB] = size(encryptedImage);

            [xSizeBinaryTable, ySizeBinaryTable] = size(binaryTable);


            for z = 1:xSizeUserImage
                for w = 1:ySizeUserImage
                    for k = (1+numberOfBits):ySizeBinaryTable
                        for q = 1:xSizeBinaryTable
                            if layer(z,w) == binaryTable(q,k)
                                switch numberOfImagesLayer
                                    case 1
                                        hiddenImages(z,w,1) = binaryTable(q,1);
                                        
                                    case 2
                                        hiddenImages(z,w,1) = binaryTable(q,1);
                                        hiddenImages(z,w,2) = binaryTable(q,2);
                                       
                                    case 3
                                        hiddenImages(z,w,1) = binaryTable(q,1);
                                        hiddenImages(z,w,2) = binaryTable(q,2);
                                        hiddenImages(z,w,3) = binaryTable(q,3);
                                        
                                    case 4
                                        hiddenImages(z,w,1) = binaryTable(q,1);
                                        hiddenImages(z,w,2) = binaryTable(q,2);
                                        hiddenImages(z,w,3) = binaryTable(q,3);
                                        hiddenImages(z,w,4) = binaryTable(q,4);
                                end

                            end        
                        end
                    end
                end
            end

            switch numberOfImagesLayer
                case 1
                    decryptedImage1 = hiddenImages(:,:,1);
                    decryptedLayer = decryptedImage1;
                case 2
                    decryptedImage1 = hiddenImages(:,:,1);
                    decryptedImage2 = hiddenImages(:,:,2);
                    decryptedLayer = cat(3,decryptedImage1,decryptedImage2);
                case 3
                    decryptedImage1 = hiddenImages(:,:,1);
                    decrytpedImage2 = hiddenImages(:,:,2);
                    decryptedImage3 = hiddenImages(:,:,3);
                    decryptedLayer = cat(3,decryptedImage1,decrytpedImage2,decryptedImage3);
                case 4
                    decryptedImage1 = hiddenImages(:,:,1);
                    decryptedImage2 = hiddenImages(:,:,2);
                    decryptedImage3 = hiddenImages(:,:,3);
                    decryptedImage4 = hiddenImages(:,:,4);
                    decryptedLayer = cat(3,decryptedImage1, decryptedImage2,decryptedImage3,decryptedImage4);
            end
           
    end


end
        
        function expandedSecretImage = expandSecret(app,secretImg)
                        
            % input: secretImg
            % input must be a 0/1 flatten image
            % output: expanded image with values 0 -255
            
            
            [xSize, ySize] = size(secretImg); % get the size of the input image
            
            expandedSecretImage = zeros(xSize); % create empty matrix for output
            
            
            for i = 1:xSize
                for j = 1:ySize
                    if secretImg(i,j) == 1 
                        expandedSecretImage(i,j) = 255;
                    end
                    if secretImg(i,j) == 0
                        expandedSecretImage(i,j) = 0; 
                    end
                end
            end


        end
        
        function flattenSecretImage = flattenSecrets(app,secretImg)        
            % input secretImg
            % input must be grayscale image
            % converts the 256 bit layer into 0 and 1
            
            
            [xSize, ySize] = size(secretImg); % get the size of the input image
            
            flattenSecretImage = zeros(xSize); % create empty matrix for output
            
            threshold = 100; % create cut off threshold
            
            for i = 1:xSize
                for j = 1:ySize
                    if secretImg(i,j) >= threshold %
                        flattenSecretImage(i,j) = 1; % set it to black
                    else
                        flattenSecretImage(i,j) = 0; % set it to white
                    end
                end
            end


        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ax = app.UIAxesGif; 

    c = ax.Color;
    ax.Color = 'black';
    ax.XLim = [0 10];
    ax.YLim = [0 10];
    axGrid = 'off';


         for t = [0:0.1:(6*pi)]
         x = ((exp(0.1*t)).*(cos(t)))+5.3;
         y = ((exp(0.12*t)).*(sin(t)))+4.9;
           
         rectangle(ax,'Position',[x,y,.1,.1],'Curvature',[1 1] , 'FaceColor','m','EdgeColor','m')
         rectangle(ax,'Position',[10.7-x,9.8-y,.1,.1],'Curvature',[1 1] , 'FaceColor','g','EdgeColor','g')
         rectangle(ax,'Position',[10.55-x,9.8-y,.1,.1],'Curvature',[1 1] , 'FaceColor','y','EdgeColor','y')
         rectangle(ax,'Position',[x-.15,y,.1,.1],'Curvature',[1 1] , 'FaceColor','b','EdgeColor','b')
         rectangle(ax,'Position',[x-.3,y,.1,.1],'Curvature',[1 1] , 'FaceColor','c','EdgeColor','c')
         rectangle(ax,'Position',[10.4-x,9.8-y,.1,.1],'Curvature',[1 1] , 'FaceColor','r','EdgeColor','r')

          drawnow
    
           end
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq;
        end

        % Callback function
        function EncryptTabSizeChanged(app, event)
            position = app.EncryptTab.Position;
            
        end

        % Callback function: EncryptButton, EncryptTab
        function EncryptButtonPushed(app, event)
            
            [y,Fs] = audioread('Beep.wav');
            sound(y,Fs,16);
            
            app.TabGroup.SelectedTab = app.EncryptTab;
            app.UploadButtonHidden1.Visible = 'off';
            app.UploadButtonHidden2.Visible = 'off';
            app.UploadButtonHidden3.Visible = 'off';
            app.UploadButtonHidden4.Visible = 'off';
            app.UploadButtonHidden5.Visible = 'off';
            app.UploadButtonHidden6.Visible = 'off';
            app.UploadButtonHidden7.Visible = 'off';
            app.UploadButtonHidden8.Visible = 'off';
            app.UploadButtonHidden9.Visible = 'off';
            app.UploadButtonHidden10.Visible = 'off';
            app.UploadButtonHidden11.Visible = 'off';
            app.UploadButtonHidden12.Visible = 'off';

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            
            selectedTab = app.TabGroup.SelectedTab;
        
            
        end

        % Button pushed function: DecryptButton
        function DecryptButtonPushed(app, event)
            [y,Fs] = audioread('Beep.wav');
            sound(y,Fs,16); 
            app.TabGroup.SelectedTab = app.DecryptTab;
        end

        % Button pushed function: HomeButtonEncrypt
        function HomeButtonEncryptPushed(app, event)
              app.TabGroup.SelectedTab = app.HomeTab;
              app.SelectNumofSecretsDropDown.Value = '0';
              
                 cla(app.UIAxesUserImage);
                    cla(app.UIAxesHidden1);
                    cla(app.UIAxesHidden2);
                    cla(app.UIAxesHidden3);
                    cla(app.UIAxesHidden4);
                    cla(app.UIAxesHidden5);
                    cla(app.UIAxesHidden6);
                    cla(app.UIAxesHidden7);
                    cla(app.UIAxesHidden8);
                    cla(app.UIAxesHidden9);
                    cla(app.UIAxesHidden10);
                    cla(app.UIAxesHidden11);
                    cla(app.UIAxesHidden12);
                    
                    app.UploadButtonHidden1.Visible = 'off';
                    app.UploadButtonHidden2.Visible = 'off';
                    app.UploadButtonHidden3.Visible = 'off';
                    app.UploadButtonHidden4.Visible = 'off';
                    app.UploadButtonHidden5.Visible = 'off';
                    app.UploadButtonHidden6.Visible = 'off';
                    app.UploadButtonHidden7.Visible = 'off';
                    app.UploadButtonHidden8.Visible = 'off';
                    app.UploadButtonHidden9.Visible = 'off';
                    app.UploadButtonHidden10.Visible = 'off';
                    app.UploadButtonHidden11.Visible = 'off';
                    app.UploadButtonHidden12.Visible = 'off';
                    
                    app.UIAxesUserImage.BackgroundColor = 'g';
                    app.UIAxesHidden1.BackgroundColor = 'k';
                    app.UIAxesHidden2.BackgroundColor = 'k';
                    app.UIAxesHidden3.BackgroundColor = 'k';
                    app.UIAxesHidden4.BackgroundColor = 'k';
                    app.UIAxesHidden5.BackgroundColor = 'k';
                    app.UIAxesHidden6.BackgroundColor = 'k';
                    app.UIAxesHidden7.BackgroundColor = 'k';
                    app.UIAxesHidden8.BackgroundColor = 'k';
                    app.UIAxesHidden9.BackgroundColor = 'k';
                    app.UIAxesHidden10.BackgroundColor = 'k';
                    app.UIAxesHidden11.BackgroundColor = 'k';
                    app.UIAxesHidden12.BackgroundColor = 'k';


        end

        % Button pushed function: HomeButton_2
        function HomeButton_2Pushed(app, event)
  
              app.TabGroup.SelectedTab = app.HomeTab;
              app.SelectNumofSecretsDropDown_2.Value = '0';
              
                 cla(app.UIAxesUserImage_2);
                    cla(app.UIAxesHiddenD1);
                    cla(app.UIAxesHiddenD2);
                    cla(app.UIAxesHiddenD3);
                    cla(app.UIAxesHiddenD4);
                    cla(app.UIAxesHiddenD5);
                    cla(app.UIAxesHiddenD6);
                    cla(app.UIAxesHiddenD7);
                    cla(app.UIAxesHiddenD8);
                    cla(app.UIAxesHiddenD9);
                    cla(app.UIAxesHiddenD10);
                    cla(app.UIAxesHiddenD11);
                    cla(app.UIAxesHiddenD12);


                    app.UIAxesUserImage.BackgroundColor = 'g';
                    app.UIAxesHiddenD1.BackgroundColor = 'k';
                    app.UIAxesHiddenD2.BackgroundColor = 'k';
                    app.UIAxesHiddenD3.BackgroundColor = 'k';
                    app.UIAxesHiddenD4.BackgroundColor = 'k';
                    app.UIAxesHiddenD5.BackgroundColor = 'k';
                    app.UIAxesHiddenD6.BackgroundColor = 'k';
                    app.UIAxesHiddenD7.BackgroundColor = 'k';
                    app.UIAxesHiddenD8.BackgroundColor = 'k';
                    app.UIAxesHiddenD9.BackgroundColor = 'k';
                    app.UIAxesHiddenD10.BackgroundColor = 'k';
                    app.UIAxesHiddenD11.BackgroundColor = 'k';
                    app.UIAxesHiddenD12.BackgroundColor = 'k';
                    
        end

        % Size changed function: HomeTab
        function HomeTabSizeChanged(app, event)
            position = app.HomeTab.Position;
            
        end

        % Button pushed function: UploadPlainImageButton
        function UploadPlainImageButtonPushed(app, event)
            % Upload original image
            File_Name = uigetfile('*.png');
            app.userImage = imread(File_Name);
            [xSize, ySize, RGB] = size(app.userImage);
            app.UIAxesUserImage.Visible = 'off';
            
            if (xSize == 400 && ySize == 400 && RGB == 3)
                % Display image and stretch to fill axes
                I = imshow(File_Name, 'Parent', app.UIAxesUserImage,...
                    'XData',[1 app.UIAxesUserImage.Position(4)],...
                    'YData',[1 app.UIAxesUserImage.Position(4)]);
             
                            
                % Set limits of axes
                app.UIAxesUserImage.XLim = [0 I.XData(2)];
                app.UIAxesUserImage.YLim = [0 I.YData(2)];
                                               
            else
                f = uifigure;
                message = sprintf('Incorrect image size. \n Please upload 400x400 image.');
                uialert(f, message,'Incorrect Size');
            end

          
        end

        % Button pushed function: UploadButtonHidden1
        function UploadButtonHidden1Pushed(app, event)
            % Upload hidden01
            File_Name = uigetfile('*.png');
            image = imread(File_Name);
            [xSize, ySize] = size(image);
     
            
            if (xSize == 400 && ySize == 400)
                % Display image and stretch to fill axes
                I = imshow(File_Name, 'Parent', app.UIAxesHidden1,...
                    'XData',[1 app.UIAxesHidden1.Position(4)],...
                    'YData',[1 app.UIAxesHidden1.Position(4)]);
             
                            
                % Set limits of axes
                app.UIAxesHidden1.XLim = [0 I.XData(2)];
                app.UIAxesHidden1.YLim = [0 I.YData(2)];


            else
                f = uifigure;
                message = sprintf('Incorrect image size. \n Please upload 400x400 image.');
                uialert(f, message,'Incorrect Size');
            end
              
        end

        % Value changed function: SelectNumofSecretsDropDown
        function SelectNumofSecretsDropDownValueChanged(app, event)
            app.numberSecrets = app.SelectNumofSecretsDropDown.Value;
            
                if app.numberSecrets == '0'
                    app.UploadButtonHidden1.Visible = 'off';
                    app.UploadButtonHidden2.Visible = 'off';
                    app.UploadButtonHidden3.Visible = 'off';
                    app.UploadButtonHidden4.Visible = 'off';
                    app.UploadButtonHidden5.Visible = 'off';
                    app.UploadButtonHidden6.Visible = 'off';
                    app.UploadButtonHidden7.Visible = 'off';
                    app.UploadButtonHidden8.Visible = 'off';
                    app.UploadButtonHidden9.Visible = 'off';
                    app.UploadButtonHidden10.Visible = 'off';
                    app.UploadButtonHidden11.Visible = 'off';
                    app.UploadButtonHidden12.Visible = 'off';
                    
                    app.UIAxesHidden1.BackgroundColor = 'k';
                    app.UIAxesHidden2.BackgroundColor = 'k';
                    app.UIAxesHidden3.BackgroundColor = 'k';
                    app.UIAxesHidden4.BackgroundColor = 'k';
                    app.UIAxesHidden5.BackgroundColor = 'k';
                    app.UIAxesHidden6.BackgroundColor = 'k';
                    app.UIAxesHidden7.BackgroundColor = 'k';
                    app.UIAxesHidden8.BackgroundColor = 'k';
                    app.UIAxesHidden9.BackgroundColor = 'k';
                    app.UIAxesHidden10.BackgroundColor = 'k';
                    app.UIAxesHidden11.BackgroundColor = 'k';
                    app.UIAxesHidden12.BackgroundColor = 'k';
                  
                    
                end
%              if app.numberSecrets == '1'
%              
%                              app.UploadButtonHidden1.Visible = 'on'; 
%                 app.UploadButtonHidden2.Visible = 'off'; % turn off all other 
%                 app.UploadButtonHidden3.Visible = 'off';
%                 app.UploadButtonHidden4.Visible = 'off';
%                 app.UploadButtonHidden5.Visible = 'off';
%                 app.UploadButtonHidden6.Visible = 'off';
%                 app.UploadButtonHidden7.Visible = 'off';
%                 app.UploadButtonHidden8.Visible = 'off';
%                 app.UploadButtonHidden9.Visible = 'off';
%                 app.UploadButtonHidden10.Visible = 'off';
%                 app.UploadButtonHidden11.Visible = 'off';
%                 app.UploadButtonHidden12.Visible = 'off';
%              
%              
%              app.UIAxesHidden1.BackgroundColor = 'g';
%                 app.UIAxesHidden2.BackgroundColor = 'k';
%                 app.UIAxesHidden3.BackgroundColor = 'k';
%                 app.UIAxesHidden4.BackgroundColor = 'k';
%                 app.UIAxesHidden5.BackgroundColor = 'k';
%                 app.UIAxesHidden6.BackgroundColor = 'k';
%                 app.UIAxesHidden7.BackgroundColor = 'k';
%                 app.UIAxesHidden8.BackgroundColor = 'k';
%                 app.UIAxesHidden9.BackgroundColor = 'k';
%                 app.UIAxesHidden10.BackgroundColor = 'k';
%                 app.UIAxesHidden11.BackgroundColor = 'k';
%                 app.UIAxesHidden12.BackgroundColor = 'k';


          %   end

             
             if app.numberSecrets == '2'
                app.UploadButtonHidden1.Visible = 'on'; % turn on vindow 1
                app.UploadButtonHidden2.Visible = 'on';
                app.UIAxesHidden1.BackgroundColor = 'g';
                app.UIAxesHidden2.BackgroundColor = 'g';
                app.UIAxesHidden3.BackgroundColor = 'k';
                app.UIAxesHidden4.BackgroundColor = 'k';
                app.UIAxesHidden5.BackgroundColor = 'k';
                app.UIAxesHidden6.BackgroundColor = 'k';
                app.UIAxesHidden7.BackgroundColor = 'k';
                app.UIAxesHidden8.BackgroundColor = 'k';
                app.UIAxesHidden9.BackgroundColor = 'k';
                app.UIAxesHidden10.BackgroundColor = 'k';
                app.UIAxesHidden11.BackgroundColor = 'k';
                app.UIAxesHidden12.BackgroundColor = 'k';

                app.UploadButtonHidden3.Visible = 'off'; % turn off all other  
                app.UploadButtonHidden4.Visible = 'off';
                app.UploadButtonHidden5.Visible = 'off';
                app.UploadButtonHidden6.Visible = 'off';
                app.UploadButtonHidden7.Visible = 'off';
                app.UploadButtonHidden8.Visible = 'off';
                app.UploadButtonHidden9.Visible = 'off';
                app.UploadButtonHidden10.Visible = 'off';
                app.UploadButtonHidden11.Visible = 'off';
                app.UploadButtonHidden12.Visible = 'off';
             
             end
             
             if app.numberSecrets == '3'
                app.UploadButtonHidden1.Visible = 'on'; % turn on vindow 1
                app.UploadButtonHidden2.Visible = 'on';
                app.UploadButtonHidden3.Visible = 'on';
                app.UIAxesHidden1.BackgroundColor = 'g';
                app.UIAxesHidden2.BackgroundColor = 'g';
                app.UIAxesHidden3.BackgroundColor = 'g';
                app.UIAxesHidden4.BackgroundColor = 'k';
                app.UIAxesHidden5.BackgroundColor = 'k';
                app.UIAxesHidden6.BackgroundColor = 'k';
                app.UIAxesHidden7.BackgroundColor = 'k';
                app.UIAxesHidden8.BackgroundColor = 'k';
                app.UIAxesHidden9.BackgroundColor = 'k';
                app.UIAxesHidden10.BackgroundColor = 'k';
                app.UIAxesHidden11.BackgroundColor = 'k';
                app.UIAxesHidden12.BackgroundColor = 'k';
                
% 
                app.UploadButtonHidden4.Visible = 'off';  % turn off all other
                app.UploadButtonHidden5.Visible = 'off';
                app.UploadButtonHidden6.Visible = 'off';
                app.UploadButtonHidden7.Visible = 'off';
                app.UploadButtonHidden8.Visible = 'off';
                app.UploadButtonHidden9.Visible = 'off';
                app.UploadButtonHidden10.Visible = 'off';
                app.UploadButtonHidden11.Visible = 'off';
                app.UploadButtonHidden12.Visible = 'off';
             
             end
            
             if app.numberSecrets == '4'
                app.UploadButtonHidden1.Visible = 'on'; % turn on vindow 1
                app.UploadButtonHidden2.Visible = 'on';
                app.UploadButtonHidden3.Visible = 'on';
                app.UploadButtonHidden4.Visible = 'on';
                app.UIAxesHidden1.BackgroundColor = 'g';
                app.UIAxesHidden2.BackgroundColor = 'g';
                app.UIAxesHidden3.BackgroundColor = 'g';
                app.UIAxesHidden4.BackgroundColor = 'g';
                app.UIAxesHidden5.BackgroundColor = 'k';
                app.UIAxesHidden6.BackgroundColor = 'k';
                app.UIAxesHidden7.BackgroundColor = 'k';
                app.UIAxesHidden8.BackgroundColor = 'k';
                app.UIAxesHidden9.BackgroundColor = 'k';
                app.UIAxesHidden10.BackgroundColor = 'k';
                app.UIAxesHidden11.BackgroundColor = 'k';
                app.UIAxesHidden12.BackgroundColor = 'k';
                

                app.UploadButtonHidden5.Visible = 'off';% turn off all other
                app.UploadButtonHidden6.Visible = 'off';
                app.UploadButtonHidden7.Visible = 'off';
                app.UploadButtonHidden8.Visible = 'off';
                app.UploadButtonHidden9.Visible = 'off';
                app.UploadButtonHidden10.Visible = 'off';
                app.UploadButtonHidden11.Visible = 'off';
                app.UploadButtonHidden12.Visible = 'off';
             
             end
%              
             if app.numberSecrets == '5'
                app.UploadButtonHidden1.Visible = 'on'; % turn on vindow 1
                app.UploadButtonHidden2.Visible = 'on';
                app.UploadButtonHidden3.Visible = 'on';
                app.UploadButtonHidden4.Visible = 'on';
                app.UploadButtonHidden5.Visible = 'on';
                app.UIAxesHidden1.BackgroundColor = 'g';
                app.UIAxesHidden2.BackgroundColor = 'g';
                app.UIAxesHidden3.BackgroundColor = 'g';
                app.UIAxesHidden4.BackgroundColor = 'g';
                app.UIAxesHidden5.BackgroundColor = 'g';
                app.UIAxesHidden6.BackgroundColor = 'k';
                app.UIAxesHidden7.BackgroundColor = 'k';
                app.UIAxesHidden8.BackgroundColor = 'k';
                app.UIAxesHidden9.BackgroundColor = 'k';
                app.UIAxesHidden10.BackgroundColor = 'k';
                app.UIAxesHidden11.BackgroundColor = 'k';
                app.UIAxesHidden12.BackgroundColor = 'k';


                app.UploadButtonHidden6.Visible = 'off'; % turn off all other
                app.UploadButtonHidden7.Visible = 'off';
                app.UploadButtonHidden8.Visible = 'off';
                app.UploadButtonHidden9.Visible = 'off';
                app.UploadButtonHidden10.Visible = 'off';
                app.UploadButtonHidden11.Visible = 'off';
                app.UploadButtonHidden12.Visible = 'off';
             
             end
%              
             if app.numberSecrets == '6'
                app.UploadButtonHidden1.Visible = 'on'; % turn on vindow 1
                app.UploadButtonHidden2.Visible = 'on';
                app.UploadButtonHidden3.Visible = 'on';
                app.UploadButtonHidden4.Visible = 'on';
                app.UploadButtonHidden5.Visible = 'on';
                app.UploadButtonHidden6.Visible = 'on';

                app.UIAxesHidden1.BackgroundColor = 'g';
                app.UIAxesHidden2.BackgroundColor = 'g';
                app.UIAxesHidden3.BackgroundColor = 'g';
                app.UIAxesHidden4.BackgroundColor = 'g';
                app.UIAxesHidden5.BackgroundColor = 'g';
                app.UIAxesHidden6.BackgroundColor = 'g';
                app.UIAxesHidden7.BackgroundColor = 'k';
                app.UIAxesHidden8.BackgroundColor = 'k';
                app.UIAxesHidden9.BackgroundColor = 'k';
                app.UIAxesHidden10.BackgroundColor = 'k';
                app.UIAxesHidden11.BackgroundColor = 'k';
                app.UIAxesHidden12.BackgroundColor = 'k';


                app.UploadButtonHidden7.Visible = 'off';  % turn off all other
                app.UploadButtonHidden8.Visible = 'off';
                app.UploadButtonHidden9.Visible = 'off';
                app.UploadButtonHidden10.Visible = 'off';
                app.UploadButtonHidden11.Visible = 'off';
                app.UploadButtonHidden12.Visible = 'off';
             
             end
             
             if app.numberSecrets == '7'
                app.UploadButtonHidden1.Visible = 'on'; % turn on vindow 1
                app.UploadButtonHidden2.Visible = 'on';
                app.UploadButtonHidden3.Visible = 'on';
                app.UploadButtonHidden4.Visible = 'on';
                app.UploadButtonHidden5.Visible = 'on';
                app.UploadButtonHidden6.Visible = 'on';
                app.UploadButtonHidden7.Visible = 'on';

                app.UIAxesHidden1.BackgroundColor = 'g';
                app.UIAxesHidden2.BackgroundColor = 'g';
                app.UIAxesHidden3.BackgroundColor = 'g';
                app.UIAxesHidden4.BackgroundColor = 'g';
                app.UIAxesHidden5.BackgroundColor = 'g';
                app.UIAxesHidden6.BackgroundColor = 'g';
                app.UIAxesHidden7.BackgroundColor = 'g';
                app.UIAxesHidden8.BackgroundColor = 'k';
                app.UIAxesHidden9.BackgroundColor = 'k';
                app.UIAxesHidden10.BackgroundColor = 'k';
                app.UIAxesHidden11.BackgroundColor = 'k';
                app.UIAxesHidden12.BackgroundColor = 'k';

                app.UploadButtonHidden8.Visible = 'off';
                app.UploadButtonHidden9.Visible = 'off';
                app.UploadButtonHidden10.Visible = 'off';
                app.UploadButtonHidden11.Visible = 'off';
                app.UploadButtonHidden12.Visible = 'off';
             
             end
             
            if app.numberSecrets == '8'
                app.UploadButtonHidden1.Visible = 'on'; % turn on vindow 1
                app.UploadButtonHidden2.Visible = 'on';
                app.UploadButtonHidden3.Visible = 'on';
                app.UploadButtonHidden4.Visible = 'on';
                app.UploadButtonHidden5.Visible = 'on';
                app.UploadButtonHidden6.Visible = 'on';
                app.UploadButtonHidden7.Visible = 'on';
                app.UploadButtonHidden8.Visible = 'on';
 
                app.UIAxesHidden1.BackgroundColor = 'g';
                app.UIAxesHidden2.BackgroundColor = 'g';
                app.UIAxesHidden3.BackgroundColor = 'g';
                app.UIAxesHidden4.BackgroundColor = 'g';
                app.UIAxesHidden5.BackgroundColor = 'g';
                app.UIAxesHidden6.BackgroundColor = 'g';
                app.UIAxesHidden7.BackgroundColor = 'g';
                app.UIAxesHidden8.BackgroundColor = 'g';
                app.UIAxesHidden9.BackgroundColor = 'k';
                app.UIAxesHidden10.BackgroundColor = 'k';
                app.UIAxesHidden11.BackgroundColor = 'k';
                app.UIAxesHidden12.BackgroundColor = 'k';

                app.UploadButtonHidden9.Visible = 'off';
                app.UploadButtonHidden10.Visible = 'off';
                app.UploadButtonHidden11.Visible = 'off';
                app.UploadButtonHidden12.Visible = 'off';
             
            end
            
           if app.numberSecrets == '9'
                app.UploadButtonHidden1.Visible = 'on'; % turn on vindow 1
                app.UploadButtonHidden2.Visible = 'on';
                app.UploadButtonHidden3.Visible = 'on';
                app.UploadButtonHidden4.Visible = 'on';
                app.UploadButtonHidden5.Visible = 'on';
                app.UploadButtonHidden6.Visible = 'on';
                app.UploadButtonHidden7.Visible = 'on';
                app.UploadButtonHidden8.Visible = 'on';
                app.UploadButtonHidden9.Visible = 'on';
 
                app.UIAxesHidden1.BackgroundColor = 'g';
                app.UIAxesHidden2.BackgroundColor = 'g';
                app.UIAxesHidden3.BackgroundColor = 'g';
                app.UIAxesHidden4.BackgroundColor = 'g';
                app.UIAxesHidden5.BackgroundColor = 'g';
                app.UIAxesHidden6.BackgroundColor = 'g';
                app.UIAxesHidden7.BackgroundColor = 'g';
                app.UIAxesHidden8.BackgroundColor = 'g';
                app.UIAxesHidden9.BackgroundColor = 'g';
                app.UIAxesHidden10.BackgroundColor = 'k';
                app.UIAxesHidden11.BackgroundColor = 'k';
                app.UIAxesHidden12.BackgroundColor = 'k';

                app.UploadButtonHidden10.Visible = 'off';
                app.UploadButtonHidden11.Visible = 'off';
                app.UploadButtonHidden12.Visible = 'off';
             
           end
%            
            if app.numberSecrets == '10'
                app.UploadButtonHidden1.Visible = 'on'; % turn on vindow 1
                app.UploadButtonHidden2.Visible = 'on';
                app.UploadButtonHidden3.Visible = 'on';
                app.UploadButtonHidden4.Visible = 'on';
                app.UploadButtonHidden5.Visible = 'on';
                app.UploadButtonHidden6.Visible = 'on';
                app.UploadButtonHidden7.Visible = 'on';
                app.UploadButtonHidden8.Visible = 'on';
                app.UploadButtonHidden9.Visible = 'on';
                app.UploadButtonHidden10.Visible = 'on';

                app.UIAxesHidden1.BackgroundColor = 'g';
                app.UIAxesHidden2.BackgroundColor = 'g';
                app.UIAxesHidden3.BackgroundColor = 'g';
                app.UIAxesHidden4.BackgroundColor = 'g';
                app.UIAxesHidden5.BackgroundColor = 'g';
                app.UIAxesHidden6.BackgroundColor = 'g';
                app.UIAxesHidden7.BackgroundColor = 'g';
                app.UIAxesHidden8.BackgroundColor = 'g';
                app.UIAxesHidden9.BackgroundColor = 'g';
                app.UIAxesHidden10.BackgroundColor = 'g';
                app.UIAxesHidden11.BackgroundColor = 'k';
                app.UIAxesHidden12.BackgroundColor = 'k';
                
                app.UploadButtonHidden11.Visible = 'off';
                app.UploadButtonHidden12.Visible = 'off';
             
            end
%              
             if app.numberSecrets == '11'
                app.UploadButtonHidden1.Visible = 'on'; % turn on vindow 1
                app.UploadButtonHidden2.Visible = 'on';
                app.UploadButtonHidden3.Visible = 'on';
                app.UploadButtonHidden4.Visible = 'on';
                app.UploadButtonHidden5.Visible = 'on';
                app.UploadButtonHidden6.Visible = 'on';
                app.UploadButtonHidden7.Visible = 'on';
                app.UploadButtonHidden8.Visible = 'on';
                app.UploadButtonHidden9.Visible = 'on';
                app.UploadButtonHidden10.Visible = 'on';
                app.UploadButtonHidden11.Visible = 'on';

                app.UIAxesHidden1.BackgroundColor = 'g';
                app.UIAxesHidden2.BackgroundColor = 'g';
                app.UIAxesHidden3.BackgroundColor = 'g';
                app.UIAxesHidden4.BackgroundColor = 'g';
                app.UIAxesHidden5.BackgroundColor = 'g';
                app.UIAxesHidden6.BackgroundColor = 'g';
                app.UIAxesHidden7.BackgroundColor = 'g';
                app.UIAxesHidden8.BackgroundColor = 'g';
                app.UIAxesHidden9.BackgroundColor = 'g';
                app.UIAxesHidden10.BackgroundColor = 'g';
                app.UIAxesHidden11.BackgroundColor = 'g';
                app.UIAxesHidden12.BackgroundColor = 'k';
                
                app.UploadButtonHidden12.Visible = 'off';
             
             end
             
             if app.numberSecrets == '12'
                app.UploadButtonHidden1.Visible = 'on'; % turn on vindow 1
                app.UploadButtonHidden2.Visible = 'on';
                app.UploadButtonHidden3.Visible = 'on';
                app.UploadButtonHidden4.Visible = 'on';
                app.UploadButtonHidden5.Visible = 'on';
                app.UploadButtonHidden6.Visible = 'on';
                app.UploadButtonHidden7.Visible = 'on';
                app.UploadButtonHidden8.Visible = 'on';
                app.UploadButtonHidden9.Visible = 'on';
                app.UploadButtonHidden10.Visible = 'on';
                app.UploadButtonHidden11.Visible = 'on';
                app.UploadButtonHidden12.Visible = 'on';

                app.UIAxesHidden1.BackgroundColor = 'g';
                app.UIAxesHidden2.BackgroundColor = 'g';
                app.UIAxesHidden3.BackgroundColor = 'g';
                app.UIAxesHidden4.BackgroundColor = 'g';
                app.UIAxesHidden5.BackgroundColor = 'g';
                app.UIAxesHidden6.BackgroundColor = 'g';
                app.UIAxesHidden7.BackgroundColor = 'g';
                app.UIAxesHidden8.BackgroundColor = 'g';
                app.UIAxesHidden9.BackgroundColor = 'g';
                app.UIAxesHidden10.BackgroundColor = 'g';
                app.UIAxesHidden11.BackgroundColor = 'g';
                app.UIAxesHidden12.BackgroundColor = 'g';
              
            
            end


        end

        % Button pushed function: UploadButtonHidden2
        function UploadButtonHidden2Pushed(app, event)
             % Upload hidden02
            File_Name = uigetfile('*.png');
            image = imread(File_Name);
            [xSize, ySize] = size(image);
           
            
            if (xSize == 400 && ySize == 400)
                % Display image and stretch to fill axes
                I = imshow(File_Name, 'Parent', app.UIAxesHidden2,...
                    'XData',[1 app.UIAxesHidden2.Position(4)],...
                    'YData',[1 app.UIAxesHidden2.Position(4)]);
             
                            
                % Set limits of axes
                app.UIAxesHidden2.XLim = [0 I.XData(2)];
                app.UIAxesHidden2.YLim = [0 I.YData(2)];


            else
                f = uifigure;
                message = sprintf('Incorrect image size. \n Please upload 400x400 image.');
                uialert(f, message,'Incorrect Size');
            end
        end

        % Button pushed function: UploadButtonHidden3
        function UploadButtonHidden3Pushed(app, event)
            % Upload hidden03
            File_Name = uigetfile('*.png');
            image = imread(File_Name);
            [xSize, ySize] = size(image);
           
            
            if (xSize == 400 && ySize == 400)
                % Display image and stretch to fill axes
                I = imshow(File_Name, 'Parent', app.UIAxesHidden3,...
                    'XData',[1 app.UIAxesHidden3.Position(4)],...
                    'YData',[1 app.UIAxesHidden3.Position(4)]);
             
                            
                % Set limits of axes
                app.UIAxesHidden3.XLim = [0 I.XData(2)];
                app.UIAxesHidden3.YLim = [0 I.YData(2)];


            else
                f = uifigure;
                message = sprintf('Incorrect image size. \n Please upload 400x400 image.');
                uialert(f, message,'Incorrect Size');
            end
        end

        % Button pushed function: UploadButtonHidden4
        function UploadButtonHidden4Pushed(app, event)
                         % Upload hidden04
            File_Name = uigetfile('*.png');
            image = imread(File_Name);
            [xSize, ySize] = size(image);
           
            
            if (xSize == 400 && ySize == 400)
                % Display image and stretch to fill axes
                I = imshow(File_Name, 'Parent', app.UIAxesHidden4,...
                    'XData',[1 app.UIAxesHidden4.Position(4)],...
                    'YData',[1 app.UIAxesHidden4.Position(4)]);
             
                            
                % Set limits of axes
                app.UIAxesHidden4.XLim = [0 I.XData(2)];
                app.UIAxesHidden4.YLim = [0 I.YData(2)];


            else
                f = uifigure;
                message = sprintf('Incorrect image size. \n Please upload 400x400 image.');
                uialert(f, message,'Incorrect Size');
            end
            
        end

        % Button pushed function: UploadButtonHidden5
        function UploadButtonHidden5Pushed(app, event)
                         % Upload hidden05
            File_Name = uigetfile('*.png');
            image = imread(File_Name);
            [xSize, ySize] = size(image);
           
            
            if (xSize == 400 && ySize == 400)
                % Display image and stretch to fill axes
                I = imshow(File_Name, 'Parent', app.UIAxesHidden5,...
                    'XData',[1 app.UIAxesHidden5.Position(4)],...
                    'YData',[1 app.UIAxesHidden5.Position(4)]);
             
                            
                % Set limits of axes
                app.UIAxesHidden5.XLim = [0 I.XData(2)];
                app.UIAxesHidden5.YLim = [0 I.YData(2)];


            else
                f = uifigure;
                message = sprintf('Incorrect image size. \n Please upload 400x400 image.');
                uialert(f, message,'Incorrect Size');
            end
        end

        % Button pushed function: UploadButtonHidden6
        function UploadButtonHidden6Pushed(app, event)
                         % Upload hidden06
            File_Name = uigetfile('*.png');
            image = imread(File_Name);
            [xSize, ySize] = size(image);
           
            
            if (xSize == 400 && ySize == 400)
                % Display image and stretch to fill axes
                I = imshow(File_Name, 'Parent', app.UIAxesHidden6,...
                    'XData',[1 app.UIAxesHidden6.Position(4)],...
                    'YData',[1 app.UIAxesHidden6.Position(4)]);
             
                            
                % Set limits of axes
                app.UIAxesHidden6.XLim = [0 I.XData(2)];
                app.UIAxesHidden6.YLim = [0 I.YData(2)];


            else
                f = uifigure;
                message = sprintf('Incorrect image size. \n Please upload 400x400 image.');
                uialert(f, message,'Incorrect Size');
            end
        end

        % Button pushed function: UploadButtonHidden7
        function UploadButtonHidden7Pushed(app, event)
                         % Upload hidden07
            File_Name = uigetfile('*.png');
            image = imread(File_Name);
            [xSize, ySize] = size(image);
           
            
            if (xSize == 400 && ySize == 400)
                % Display image and stretch to fill axes
                I = imshow(File_Name, 'Parent', app.UIAxesHidden7,...
                    'XData',[1 app.UIAxesHidden7.Position(4)],...
                    'YData',[1 app.UIAxesHidden7.Position(4)]);
             
                            
                % Set limits of axes
                app.UIAxesHidden7.XLim = [0 I.XData(2)];
                app.UIAxesHidden7.YLim = [0 I.YData(2)];


            else
                f = uifigure;
                message = sprintf('Incorrect image size. \n Please upload 400x400 image.');
                uialert(f, message,'Incorrect Size');
            end
        end

        % Button pushed function: UploadButtonHidden8
        function UploadButtonHidden8Pushed(app, event)
                         % Upload hidden08
            File_Name = uigetfile('*.png');
            image = imread(File_Name);
            [xSize, ySize] = size(image);
           
            
            if (xSize == 400 && ySize == 400)
                % Display image and stretch to fill axes
                I = imshow(File_Name, 'Parent', app.UIAxesHidden8,...
                    'XData',[1 app.UIAxesHidden8.Position(4)],...
                    'YData',[1 app.UIAxesHidden8.Position(4)]);
             
                            
                % Set limits of axes
                app.UIAxesHidden8.XLim = [0 I.XData(2)];
                app.UIAxesHidden8.YLim = [0 I.YData(2)];


            else
                f = uifigure;
                message = sprintf('Incorrect image size. \n Please upload 400x400 image.');
                uialert(f, message,'Incorrect Size');
            end
        end

        % Button pushed function: UploadButtonHidden9
        function UploadButtonHidden9Pushed(app, event)
                         % Upload hidden09
            File_Name = uigetfile('*.png');
            image = imread(File_Name);
            [xSize, ySize] = size(image);
           
            
            if (xSize == 400 && ySize == 400)
                % Display image and stretch to fill axes
                I = imshow(File_Name, 'Parent', app.UIAxesHidden9,...
                    'XData',[1 app.UIAxesHidden9.Position(4)],...
                    'YData',[1 app.UIAxesHidden9.Position(4)]);
             
                            
                % Set limits of axes
                app.UIAxesHidden9.XLim = [0 I.XData(2)];
                app.UIAxesHidden9.YLim = [0 I.YData(2)];


            else
                f = uifigure;
                message = sprintf('Incorrect image size. \n Please upload 400x400 image.');
                uialert(f, message,'Incorrect Size');
            end
        end

        % Button pushed function: UploadButtonHidden10
        function UploadButtonHidden10Pushed(app, event)
                         % Upload hidden10
            File_Name = uigetfile('*.png');
            image = imread(File_Name);
            [xSize, ySize] = size(image);
           
            
            if (xSize == 400 && ySize == 400)
                % Display image and stretch to fill axes
                I = imshow(File_Name, 'Parent', app.UIAxesHidden10,...
                    'XData',[1 app.UIAxesHidden10.Position(4)],...
                    'YData',[1 app.UIAxesHidden10.Position(4)]);
             
                            
                % Set limits of axes
                app.UIAxesHidden10.XLim = [0 I.XData(2)];
                app.UIAxesHidden10.YLim = [0 I.YData(2)];


            else
                f = uifigure;
                message = sprintf('Incorrect image size. \n Please upload 400x400 image.');
                uialert(f, message,'Incorrect Size');
            end
        end

        % Button pushed function: UploadButtonHidden11
        function UploadButtonHidden11Pushed(app, event)
                         % Upload hidden02
            File_Name = uigetfile('*.png');
            image= imread(File_Name);
            [xSize, ySize] = size(image);
           
            
            if (xSize == 400 && ySize == 400)
                % Display image and stretch to fill axes
                I = imshow(File_Name, 'Parent', app.UIAxesHidden11,...
                    'XData',[1 app.UIAxesHidden11.Position(4)],...
                    'YData',[1 app.UIAxesHidden11.Position(4)]);
             
                            
                % Set limits of axes
                app.UIAxesHidden11.XLim = [0 I.XData(2)];
                app.UIAxesHidden11.YLim = [0 I.YData(2)];


            else
                f = uifigure;
                message = sprintf('Incorrect image size. \n Please upload 400x400 image.');
                uialert(f, message,'Incorrect Size');
            end
        end

        % Button pushed function: UploadButtonHidden12
        function UploadButtonHidden12Pushed(app, event)
                         % Upload hidden12
            File_Name = uigetfile('*.png');
            image = imread(File_Name);
            [xSize, ySize] = size(image);
           
            
            if (xSize == 400 && ySize == 400)
                % Display image and stretch to fill axes
                I = imshow(File_Name, 'Parent', app.UIAxesHidden12,...
                    'XData',[1 app.UIAxesHidden12.Position(4)],...
                    'YData',[1 app.UIAxesHidden12.Position(4)]);
             
                            
                % Set limits of axes
                app.UIAxesHidden12.XLim = [0 I.XData(2)];
                app.UIAxesHidden12.YLim = [0 I.YData(2)];


            else
                f = uifigure;
                message = sprintf('Incorrect image size. \n Please upload 400x400 image.');
                uialert(f, message,'Incorrect Size');
            end
        end

        % Callback function
        function PlayButtonPushed(app, event)
            File_Name = uigetfile('matrix.gif');
            orgImage = imread('matrix.gif');


                I = imshow(File_Name, 'Parent', app.UIAxesMatrix,...
                    'XData',[1 app.UIAxesMatrix.Position(4)],...
                    'YData',[1 app.UIAxesMatrix.Position(4)]);


        end

        % Callback function: EncryptImageButton_2, UIFigure
        function EncryptImageButtonDown(app, event)
            [y,Fs] = audioread('Beep.wav');
            sound(y,Fs,16);
            
            userImage = getimage(app.UIAxesUserImage);
            hidden1 = getimage(app.UIAxesHidden1);
            hidden2 = getimage(app.UIAxesHidden2);
            hidden3 = getimage(app.UIAxesHidden3);
            hidden4 = getimage(app.UIAxesHidden4);
            hidden5 = getimage(app.UIAxesHidden5);
            hidden6 = getimage(app.UIAxesHidden6);
            hidden7 = getimage(app.UIAxesHidden7);
            hidden8 = getimage(app.UIAxesHidden8);
            hidden9 = getimage(app.UIAxesHidden9);
            hidden10 = getimage(app.UIAxesHidden10);
            hidden11 = getimage(app.UIAxesHidden11);
            hidden12 = getimage(app.UIAxesHidden12);


             % SELECTING METHOD
             % COCK
             methodValue = app.methodSelection.Value; 
             if methodValue == 'Standard'
                switch app.numberSecrets
                    case '1'
                        encryptImage(app, userImage, hidden1);
                    case '2'
                        encryptImage(app, userImage, hidden1, hidden2);
                    case '3'
                        encryptImage(app, userImage, hidden1, hidden2, hidden3);
                    case '4'
                        encryptImage(app, userImage, hidden1, hidden2, hidden3, hidden4);
                    case '5'
                        encryptImage(app, userImage, hidden1, hidden2, hidden3, hidden4, hidden5);
                    case '6'
                        encryptImage(app, userImage, hidden1, hidden2, hidden3, hidden4, hidden5, hidden6);
                    case '7'
                        encryptImage(app, userImage, hidden1, hidden2, hidden3, hidden4, hidden5, hidden6, hidden7);
                    case '8'
                        encryptImage(app, userImage, hidden1, hidden2, hidden3, hidden4, hidden5, hidden6, hidden7, hidden8);
                    case '9'
                        encryptImage(app, userImage, hidden1, hidden2, hidden3, hidden4, hidden5, hidden6, hidden7, hidden8, hidden9);
                    case '10'
                        encryptImage(app, userImage, hidden1, hidden2, hidden3, hidden4, hidden5, hidden6, hidden7, hidden8, hidden9, hidden10);
                    case '11'
                        encryptImage(app, userImage, hidden1, hidden2, hidden3, hidden4, hidden5, hidden6, hidden7, hidden8, hidden9, hidden10, hidden11);
                    case '12'
                        encryptImage(app, userImage, hidden1, hidden2, hidden3, hidden4, hidden5, hidden6, hidden7, hidden8, hidden9, hidden10, hidden11, hidden12);
                end
             end
             
             if methodValue == 'Layer   '
                 switch app.numberSecrets
                    case '1'
                        encryptImageLayer(app, userImage, hidden1);
                    case '2'
                        encryptImageLayer(app, userImage, hidden1, hidden2);
                    case '3'
                        encryptImageLayer(app, userImage, hidden1, hidden2, hidden3);
                    case '4'
                        encryptImageLayer(app, userImage, hidden1, hidden2, hidden3, hidden4);
                    case '5'
                        encryptImageLayer(app, userImage, hidden1, hidden2, hidden3, hidden4, hidden5);
                    case '6'
                        encryptImageLayer(app, userImage, hidden1, hidden2, hidden3, hidden4, hidden5, hidden6);
                    case '7'
                        encryptImageLayer(app, userImage, hidden1, hidden2, hidden3, hidden4, hidden5, hidden6, hidden7);
                    case '8'
                        encryptImageLayer(app, userImage, hidden1, hidden2, hidden3, hidden4, hidden5, hidden6, hidden7, hidden8);
                    case '9'
                        encryptImageLayer(app, userImage, hidden1, hidden2, hidden3, hidden4, hidden5, hidden6, hidden7, hidden8, hidden9);
                    case '10'
                        encryptImageLayer(app, userImage, hidden1, hidden2, hidden3, hidden4, hidden5, hidden6, hidden7, hidden8, hidden9, hidden10);
                    case '11'
                        encryptImageLayer(app, userImage, hidden1, hidden2, hidden3, hidden4, hidden5, hidden6, hidden7, hidden8, hidden9, hidden10, hidden11);
                    case '12'
                        encryptImageLayer(app, userImage, hidden1, hidden2, hidden3, hidden4, hidden5, hidden6, hidden7, hidden8, hidden9, hidden10, hidden11, hidden12);
                end
                 
         end
               % encryptImageLayers(app, userImage, hidden1, hidden2, hidden3, hidden4, hidden5, hidden6, hidden7, hidden8, hidden9, hidden10, hidden11, hidden12);

        end

        % Value changed function: SelectNumofSecretsDropDown_2
        function SelectNumofSecretsDropDown_2ValueChanged(app, event)
            app.numberSecrets = app.SelectNumofSecretsDropDown_2.Value;
            
                if app.numberSecrets == '0'
                   
                    app.UIAxesHiddenD1.BackgroundColor = 'k';
                    app.UIAxesHiddenD2.BackgroundColor = 'k';
                    app.UIAxesHiddenD3.BackgroundColor = 'k';
                    app.UIAxesHiddenD4.BackgroundColor = 'k';
                    app.UIAxesHiddenD5.BackgroundColor = 'k';
                    app.UIAxesHiddenD6.BackgroundColor = 'k';
                    app.UIAxesHiddenD7.BackgroundColor = 'k';
                    app.UIAxesHiddenD8.BackgroundColor = 'k';
                    app.UIAxesHiddenD9.BackgroundColor = 'k';
                    app.UIAxesHiddenD10.BackgroundColor = 'k';
                    app.UIAxesHiddenD11.BackgroundColor = 'k';
                    app.UIAxesHiddenD12.BackgroundColor = 'k';
                  
                    
                end
%              if app.numberSecrets == '1'
%              
%              
%              
%              app.UIAxesHidden1.BackgroundColor = 'g';
%                 app.UIAxesHiddenD2.BackgroundColor = 'k';
%                 app.UIAxesHidden3.BackgroundColor = 'k';
%                 app.UIAxesHidden4.BackgroundColor = 'k';
%                 app.UIAxesHidden5.BackgroundColor = 'k';
%                 app.UIAxesHidden6.BackgroundColor = 'k';
%                 app.UIAxesHidden7.BackgroundColor = 'k';
%                 app.UIAxesHidden8.BackgroundColor = 'k';
%                 app.UIAxesHidden9.BackgroundColor = 'k';
%                 app.UIAxesHidden10.BackgroundColor = 'k';
%                 app.UIAxesHidden11.BackgroundColor = 'k';
%                 app.UIAxesHidden12.BackgroundColor = 'k';


          %   end

             
             if app.numberSecrets == '2'

                app.UIAxesHiddenD1.BackgroundColor = 'g';
                app.UIAxesHiddenD2.BackgroundColor = 'g';
                app.UIAxesHiddenD3.BackgroundColor = 'k';
                app.UIAxesHiddenD4.BackgroundColor = 'k';
                app.UIAxesHiddenD5.BackgroundColor = 'k';
                app.UIAxesHiddenD6.BackgroundColor = 'k';
                app.UIAxesHiddenD7.BackgroundColor = 'k';
                app.UIAxesHiddenD8.BackgroundColor = 'k';
                app.UIAxesHiddenD9.BackgroundColor = 'k';
                app.UIAxesHiddenD10.BackgroundColor = 'k';
                app.UIAxesHiddenD11.BackgroundColor = 'k';
                app.UIAxesHiddenD12.BackgroundColor = 'k';

             
             end
             
             if app.numberSecrets == '3'

                app.UIAxesHiddenD1.BackgroundColor = 'g';
                app.UIAxesHiddenD2.BackgroundColor = 'g';
                app.UIAxesHiddenD3.BackgroundColor = 'g';
                app.UIAxesHiddenD4.BackgroundColor = 'k';
                app.UIAxesHiddenD5.BackgroundColor = 'k';
                app.UIAxesHiddenD6.BackgroundColor = 'k';
                app.UIAxesHiddenD7.BackgroundColor = 'k';
                app.UIAxesHiddenD8.BackgroundColor = 'k';
                app.UIAxesHiddenD9.BackgroundColor = 'k';
                app.UIAxesHiddenD10.BackgroundColor = 'k';
                app.UIAxesHiddenD11.BackgroundColor = 'k';
                app.UIAxesHiddenD12.BackgroundColor = 'k';


             end
            
             if app.numberSecrets == '4'
                
                app.UIAxesHiddenD1.BackgroundColor = 'g';
                app.UIAxesHiddenD2.BackgroundColor = 'g';
                app.UIAxesHiddenD3.BackgroundColor = 'g';
                app.UIAxesHiddenD4.BackgroundColor = 'g';
                app.UIAxesHiddenD5.BackgroundColor = 'k';
                app.UIAxesHiddenD6.BackgroundColor = 'k';
                app.UIAxesHiddenD7.BackgroundColor = 'k';
                app.UIAxesHiddenD8.BackgroundColor = 'k';
                app.UIAxesHiddenD9.BackgroundColor = 'k';
                app.UIAxesHiddenD10.BackgroundColor = 'k';
                app.UIAxesHiddenD11.BackgroundColor = 'k';
                app.UIAxesHiddenD12.BackgroundColor = 'k';
             
             end
%              
             if app.numberSecrets == '5'

                app.UIAxesHiddenD1.BackgroundColor = 'g';
                app.UIAxesHiddenD2.BackgroundColor = 'g';
                app.UIAxesHiddenD3.BackgroundColor = 'g';
                app.UIAxesHiddenD4.BackgroundColor = 'g';
                app.UIAxesHiddenD5.BackgroundColor = 'g';
                app.UIAxesHiddenD6.BackgroundColor = 'k';
                app.UIAxesHiddenD7.BackgroundColor = 'k';
                app.UIAxesHiddenD8.BackgroundColor = 'k';
                app.UIAxesHiddenD9.BackgroundColor = 'k';
                app.UIAxesHiddenD10.BackgroundColor = 'k';
                app.UIAxesHiddenD11.BackgroundColor = 'k';
                app.UIAxesHiddenD12.BackgroundColor = 'k';

             
             end
%              
             if app.numberSecrets == '6'


                app.UIAxesHiddenD1.BackgroundColor = 'g';
                app.UIAxesHiddenD2.BackgroundColor = 'g';
                app.UIAxesHiddenD3.BackgroundColor = 'g';
                app.UIAxesHiddenD4.BackgroundColor = 'g';
                app.UIAxesHiddenD5.BackgroundColor = 'g';
                app.UIAxesHiddenD6.BackgroundColor = 'g';
                app.UIAxesHiddenD7.BackgroundColor = 'k';
                app.UIAxesHiddenD8.BackgroundColor = 'k';
                app.UIAxesHiddenD9.BackgroundColor = 'k';
                app.UIAxesHiddenD10.BackgroundColor = 'k';
                app.UIAxesHiddenD11.BackgroundColor = 'k';
                app.UIAxesHiddenD12.BackgroundColor = 'k';

             
             end
             
             if app.numberSecrets == '7'


                app.UIAxesHiddenD1.BackgroundColor = 'g';
                app.UIAxesHiddenD2.BackgroundColor = 'g';
                app.UIAxesHiddenD3.BackgroundColor = 'g';
                app.UIAxesHiddenD4.BackgroundColor = 'g';
                app.UIAxesHiddenD5.BackgroundColor = 'g';
                app.UIAxesHiddenD6.BackgroundColor = 'g';
                app.UIAxesHiddenD7.BackgroundColor = 'g';
                app.UIAxesHiddenD8.BackgroundColor = 'k';
                app.UIAxesHiddenD9.BackgroundColor = 'k';
                app.UIAxesHiddenD10.BackgroundColor = 'k';
                app.UIAxesHiddenD11.BackgroundColor = 'k';
                app.UIAxesHiddenD12.BackgroundColor = 'k';

             
             end
             
            if app.numberSecrets == '8'

 
                app.UIAxesHiddenD1.BackgroundColor = 'g';
                app.UIAxesHiddenD2.BackgroundColor = 'g';
                app.UIAxesHiddenD3.BackgroundColor = 'g';
                app.UIAxesHiddenD4.BackgroundColor = 'g';
                app.UIAxesHiddenD5.BackgroundColor = 'g';
                app.UIAxesHiddenD6.BackgroundColor = 'g';
                app.UIAxesHiddenD7.BackgroundColor = 'g';
                app.UIAxesHiddenD8.BackgroundColor = 'g';
                app.UIAxesHiddenD9.BackgroundColor = 'k';
                app.UIAxesHiddenD10.BackgroundColor = 'k';
                app.UIAxesHiddenD11.BackgroundColor = 'k';
                app.UIAxesHiddenD12.BackgroundColor = 'k';

             
            end
            
           if app.numberSecrets == '9'

 
                app.UIAxesHiddenD1.BackgroundColor = 'g';
                app.UIAxesHiddenD2.BackgroundColor = 'g';
                app.UIAxesHiddenD3.BackgroundColor = 'g';
                app.UIAxesHiddenD4.BackgroundColor = 'g';
                app.UIAxesHiddenD5.BackgroundColor = 'g';
                app.UIAxesHiddenD6.BackgroundColor = 'g';
                app.UIAxesHiddenD7.BackgroundColor = 'g';
                app.UIAxesHiddenD8.BackgroundColor = 'g';
                app.UIAxesHiddenD9.BackgroundColor = 'g';
                app.UIAxesHiddenD10.BackgroundColor = 'k';
                app.UIAxesHiddenD11.BackgroundColor = 'k';
                app.UIAxesHiddenD12.BackgroundColor = 'k';

             
           end
%            
            if app.numberSecrets == '10'


                app.UIAxesHiddenD1.BackgroundColor = 'g';
                app.UIAxesHiddenD2.BackgroundColor = 'g';
                app.UIAxesHiddenD3.BackgroundColor = 'g';
                app.UIAxesHiddenD4.BackgroundColor = 'g';
                app.UIAxesHiddenD5.BackgroundColor = 'g';
                app.UIAxesHiddenD6.BackgroundColor = 'g';
                app.UIAxesHiddenD7.BackgroundColor = 'g';
                app.UIAxesHiddenD8.BackgroundColor = 'g';
                app.UIAxesHiddenD9.BackgroundColor = 'g';
                app.UIAxesHiddenD10.BackgroundColor = 'g';
                app.UIAxesHiddenD11.BackgroundColor = 'k';
                app.UIAxesHiddenD12.BackgroundColor = 'k';

             
            end
%              
             if app.numberSecrets == '11'


                app.UIAxesHiddenD1.BackgroundColor = 'g';
                app.UIAxesHiddenD2.BackgroundColor = 'g';
                app.UIAxesHiddenD3.BackgroundColor = 'g';
                app.UIAxesHiddenD4.BackgroundColor = 'g';
                app.UIAxesHiddenD5.BackgroundColor = 'g';
                app.UIAxesHiddenD6.BackgroundColor = 'g';
                app.UIAxesHiddenD7.BackgroundColor = 'g';
                app.UIAxesHiddenD8.BackgroundColor = 'g';
                app.UIAxesHiddenD9.BackgroundColor = 'g';
                app.UIAxesHiddenD10.BackgroundColor = 'g';
                app.UIAxesHiddenD11.BackgroundColor = 'g';
                app.UIAxesHiddenD12.BackgroundColor = 'k';

             
             end
             
             if app.numberSecrets == '12'

                app.UIAxesHiddenD1.BackgroundColor = 'g';
                app.UIAxesHiddenD2.BackgroundColor = 'g';
                app.UIAxesHiddenD3.BackgroundColor = 'g';
                app.UIAxesHiddenD4.BackgroundColor = 'g';
                app.UIAxesHiddenD5.BackgroundColor = 'g';
                app.UIAxesHiddenD6.BackgroundColor = 'g';
                app.UIAxesHiddenD7.BackgroundColor = 'g';
                app.UIAxesHiddenD8.BackgroundColor = 'g';
                app.UIAxesHiddenD9.BackgroundColor = 'g';
                app.UIAxesHiddenD10.BackgroundColor = 'g';
                app.UIAxesHiddenD11.BackgroundColor = 'g';
                app.UIAxesHiddenD12.BackgroundColor = 'g';
              
            
            end

        end

        % Button pushed function: DecryptImageButton
        function DecryptImageButtonPushed(app, event)
            [y,Fs] = audioread('Beep.wav');
            sound(y,Fs,16);
            
            userImage = getimage(app.UIAxesUserImage_2)
                     
            switch app.SelectMethodDropDown.Value
                case 'Standard'
                    decryptImage(app, userImage, app.numberSecrets);
                case 'Layers  '
                    decryptImageLayer(app, userImage, app.numberSecrets);   
            end
        end

        % Button pushed function: UploadEncryptedImageButton
        function UploadEncryptedImageButtonPushed(app, event)
            % Upload encrypted image
            File_Name = uigetfile('*.png');
            image = imread(File_Name);
            [xSize, ySize, RGB] = size(image);
            app.UIAxesUserImage_2.Visible = 'off';
            
            if (xSize == 400 && ySize == 400 && RGB == 3)
                % Display image and stretch to fill axes
                I = imshow(File_Name, 'Parent', app.UIAxesUserImage_2,...
                    'XData',[1 app.UIAxesUserImage_2.Position(4)],...
                    'YData',[1 app.UIAxesUserImage_2.Position(4)]);
             
                            
                % Set limits of axes
                app.UIAxesUserImage_2.XLim = [0 I.XData(2)];
                app.UIAxesUserImage_2.YLim = [0 I.YData(2)];
                                               
            else
                f = uifigure;
                message = sprintf('Incorrect image size. \n Please upload 400x400 image.');
                uialert(f, message,'Incorrect Size');
            end

        end

        % Callback function
        function ChooseMethodDropDownValueChanged(app, event)
            
        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.InfoTab;
        end

        % Button pushed function: HomeButtonEncrypt_2
        function HomeButtonEncrypt_2Pushed(app, event)
             app.TabGroup.SelectedTab = app.HomeTab;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @EncryptImageButtonDown, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.SizeChangedFcn = createCallbackFcn(app, @HomeTabSizeChanged, true);
            app.HomeTab.Title = 'Home';
            app.UIAxesGif = uiaxes(app.HomeTab);
            app.UIAxesGif.XTick = [];
            app.UIAxesGif.YTick = [];
            app.UIAxesGif.YTickLabel = '';
            app.EncryptButton = uibutton(app.HomeTab, 'push');
            app.EncryptButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptButtonPushed, true);
            app.EncryptButton.Text = 'Encrypt';
            app.DecryptButton = uibutton(app.HomeTab, 'push');
            app.DecryptButton.ButtonPushedFcn = createCallbackFcn(app, @DecryptButtonPushed, true);
            app.DecryptButton.Text = 'Decrypt';
            app.ExitButton = uibutton(app.HomeTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.SPACEMONKEYSLabel = uilabel(app.HomeTab);
            app.SPACEMONKEYSLabel.Text = 'SPACE MONKEYS';
            app.InfoButton = uibutton(app.HomeTab, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Text = 'Info';
            app.EncryptTab = uitab(app.TabGroup);
            app.EncryptTab.SizeChangedFcn = createCallbackFcn(app, @EncryptButtonPushed, true);
            app.EncryptTab.Title = 'Encrypt';
            app.HomeButtonEncrypt = uibutton(app.EncryptTab, 'push');
            app.HomeButtonEncrypt.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonEncryptPushed, true);
            app.HomeButtonEncrypt.Text = 'Home';
            app.UIAxesUserImage = uiaxes(app.EncryptTab);
            app.UIAxesUserImage.XTick = [];
            app.UIAxesUserImage.YTick = [];
            app.UIAxesUserImage.ZTick = [];
            app.UploadPlainImageButton = uibutton(app.EncryptTab, 'push');
            app.UploadPlainImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadPlainImageButtonPushed, true);
            app.UploadPlainImageButton.Text = 'Upload Plain Image';
            app.SelectNumofSecretsDropDownLabel = uilabel(app.EncryptTab);
            app.SelectNumofSecretsDropDownLabel.Text = 'Select Num of Secrets';
            app.SelectNumofSecretsDropDown = uidropdown(app.EncryptTab);
            app.SelectNumofSecretsDropDown.Items = {'0', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.SelectNumofSecretsDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectNumofSecretsDropDownValueChanged, true);
            app.SelectNumofSecretsDropDown.Value = '0';
            app.UploadButtonHidden1 = uibutton(app.EncryptTab, 'push');
            app.UploadButtonHidden1.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonHidden1Pushed, true);
            app.UploadButtonHidden1.Text = 'Upload';
            app.UIAxesHidden1 = uiaxes(app.EncryptTab);
            app.UIAxesHidden1.XTick = [];
            app.UIAxesHidden1.YTick = [];
            app.UIAxesHidden1.ZTick = [];
            app.UploadButtonHidden2 = uibutton(app.EncryptTab, 'push');
            app.UploadButtonHidden2.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonHidden2Pushed, true);
            app.UploadButtonHidden2.Text = 'Upload';
            app.UploadButtonHidden3 = uibutton(app.EncryptTab, 'push');
            app.UploadButtonHidden3.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonHidden3Pushed, true);
            app.UploadButtonHidden3.Text = 'Upload';
            app.UIAxesHidden2 = uiaxes(app.EncryptTab);
            app.UIAxesHidden2.XTick = [];
            app.UIAxesHidden2.YTick = [];
            app.UIAxesHidden2.ZTick = [];
            app.UIAxesHidden3 = uiaxes(app.EncryptTab);
            app.UIAxesHidden3.XTick = [];
            app.UIAxesHidden3.YTick = [];
            app.UIAxesHidden3.ZTick = [];
            app.UIAxesHidden4 = uiaxes(app.EncryptTab);
            app.UIAxesHidden4.XTick = [];
            app.UIAxesHidden4.YTick = [];
            app.UIAxesHidden4.ZTick = [];
            app.UIAxesHidden7 = uiaxes(app.EncryptTab);
            app.UIAxesHidden7.XTick = [];
            app.UIAxesHidden7.YTick = [];
            app.UIAxesHidden7.ZTick = [];
            app.UIAxesHidden10 = uiaxes(app.EncryptTab);
            app.UIAxesHidden10.XTick = [];
            app.UIAxesHidden10.YTick = [];
            app.UIAxesHidden10.ZTick = [];
            app.UIAxesHidden5 = uiaxes(app.EncryptTab);
            app.UIAxesHidden5.XTick = [];
            app.UIAxesHidden5.YTick = [];
            app.UIAxesHidden5.ZTick = [];
            app.UIAxesHidden6 = uiaxes(app.EncryptTab);
            app.UIAxesHidden6.XTick = [];
            app.UIAxesHidden6.YTick = [];
            app.UIAxesHidden6.ZTick = [];
            app.UIAxesHidden8 = uiaxes(app.EncryptTab);
            app.UIAxesHidden8.XTick = [];
            app.UIAxesHidden8.YTick = [];
            app.UIAxesHidden8.ZTick = [];
            app.UIAxesHidden9 = uiaxes(app.EncryptTab);
            app.UIAxesHidden9.XTick = [];
            app.UIAxesHidden9.YTick = [];
            app.UIAxesHidden9.ZTick = [];
            app.UIAxesHidden11 = uiaxes(app.EncryptTab);
            app.UIAxesHidden11.XTick = [];
            app.UIAxesHidden11.YTick = [];
            app.UIAxesHidden11.ZTick = [];
            app.UIAxesHidden12 = uiaxes(app.EncryptTab);
            app.UIAxesHidden12.XTick = [];
            app.UIAxesHidden12.YTick = [];
            app.UIAxesHidden12.ZTick = [];
            app.UploadButtonHidden4 = uibutton(app.EncryptTab, 'push');
            app.UploadButtonHidden4.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonHidden4Pushed, true);
            app.UploadButtonHidden4.Text = 'Upload';
            app.UploadButtonHidden5 = uibutton(app.EncryptTab, 'push');
            app.UploadButtonHidden5.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonHidden5Pushed, true);
            app.UploadButtonHidden5.Text = 'Upload';
            app.UploadButtonHidden6 = uibutton(app.EncryptTab, 'push');
            app.UploadButtonHidden6.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonHidden6Pushed, true);
            app.UploadButtonHidden6.Text = 'Upload';
            app.UploadButtonHidden7 = uibutton(app.EncryptTab, 'push');
            app.UploadButtonHidden7.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonHidden7Pushed, true);
            app.UploadButtonHidden7.Text = 'Upload';
            app.UploadButtonHidden8 = uibutton(app.EncryptTab, 'push');
            app.UploadButtonHidden8.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonHidden8Pushed, true);
            app.UploadButtonHidden8.Text = 'Upload';
            app.UploadButtonHidden9 = uibutton(app.EncryptTab, 'push');
            app.UploadButtonHidden9.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonHidden9Pushed, true);
            app.UploadButtonHidden9.Text = 'Upload';
            app.UploadButtonHidden10 = uibutton(app.EncryptTab, 'push');
            app.UploadButtonHidden10.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonHidden10Pushed, true);
            app.UploadButtonHidden10.Text = 'Upload';
            app.UploadButtonHidden11 = uibutton(app.EncryptTab, 'push');
            app.UploadButtonHidden11.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonHidden11Pushed, true);
            app.UploadButtonHidden11.Text = 'Upload';
            app.UploadButtonHidden12 = uibutton(app.EncryptTab, 'push');
            app.UploadButtonHidden12.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonHidden12Pushed, true);
            app.UploadButtonHidden12.Text = 'Upload';
            app.EncryptImageButton_2 = uibutton(app.EncryptTab, 'push');
            app.EncryptImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @EncryptImageButtonDown, true);
            app.EncryptImageButton_2.Icon = 'MonkeyEncrypt.png';
            app.EncryptImageButton_2.Text = 'Encrypt';
            app.MethodSelectionDropDownLabel = uilabel(app.EncryptTab);
            app.MethodSelectionDropDownLabel.Text = 'Method Selection';
            app.methodSelection = uidropdown(app.EncryptTab);
            app.methodSelection.Items = {'None    ', 'Standard', 'Layer   ', ''};
            app.methodSelection.Value = 'None    ';
            app.DecryptTab = uitab(app.TabGroup);
            app.DecryptTab.Title = 'Decrypt';
            app.HomeButton_2 = uibutton(app.DecryptTab, 'push');
            app.HomeButton_2.ButtonPushedFcn = createCallbackFcn(app, @HomeButton_2Pushed, true);
            app.HomeButton_2.Text = 'Home';
            app.UIAxesUserImage_2 = uiaxes(app.DecryptTab);
            app.UIAxesUserImage_2.XTick = [];
            app.UIAxesUserImage_2.YTick = [];
            app.UIAxesUserImage_2.ZTick = [];
            app.UploadEncryptedImageButton = uibutton(app.DecryptTab, 'push');
            app.UploadEncryptedImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadEncryptedImageButtonPushed, true);
            app.UploadEncryptedImageButton.Text = 'Upload Encrypted Image';
            app.SelectNumofSecretsDropDown_2Label = uilabel(app.DecryptTab);
            app.SelectNumofSecretsDropDown_2Label.Text = 'Select Num of Secrets';
            app.SelectNumofSecretsDropDown_2 = uidropdown(app.DecryptTab);
            app.SelectNumofSecretsDropDown_2.Items = {'0', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.SelectNumofSecretsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SelectNumofSecretsDropDown_2ValueChanged, true);
            app.SelectNumofSecretsDropDown_2.Value = '0';
            app.DecryptImageButton = uibutton(app.DecryptTab, 'push');
            app.DecryptImageButton.ButtonPushedFcn = createCallbackFcn(app, @DecryptImageButtonPushed, true);
            app.DecryptImageButton.Icon = 'SpaceMonkey_Icon.jpg';
            app.DecryptImageButton.Text = 'Decrypt';
            app.UIAxesHiddenD1 = uiaxes(app.DecryptTab);
            app.UIAxesHiddenD1.XTick = [];
            app.UIAxesHiddenD1.YTick = [];
            app.UIAxesHiddenD1.ZTick = [];
            app.UIAxesHiddenD2 = uiaxes(app.DecryptTab);
            app.UIAxesHiddenD2.XTick = [];
            app.UIAxesHiddenD2.YTick = [];
            app.UIAxesHiddenD2.ZTick = [];
            app.UIAxesHiddenD3 = uiaxes(app.DecryptTab);
            app.UIAxesHiddenD3.XTick = [];
            app.UIAxesHiddenD3.YTick = [];
            app.UIAxesHiddenD3.ZTick = [];
            app.UIAxesHiddenD4 = uiaxes(app.DecryptTab);
            app.UIAxesHiddenD4.XTick = [];
            app.UIAxesHiddenD4.YTick = [];
            app.UIAxesHiddenD4.ZTick = [];
            app.UIAxesHiddenD7 = uiaxes(app.DecryptTab);
            app.UIAxesHiddenD7.XTick = [];
            app.UIAxesHiddenD7.YTick = [];
            app.UIAxesHiddenD7.ZTick = [];
            app.UIAxesHiddenD5 = uiaxes(app.DecryptTab);
            app.UIAxesHiddenD5.XTick = [];
            app.UIAxesHiddenD5.YTick = [];
            app.UIAxesHiddenD5.ZTick = [];
            app.UIAxesHiddenD6 = uiaxes(app.DecryptTab);
            app.UIAxesHiddenD6.XTick = [];
            app.UIAxesHiddenD6.YTick = [];
            app.UIAxesHiddenD6.ZTick = [];
            app.UIAxesHiddenD8 = uiaxes(app.DecryptTab);
            app.UIAxesHiddenD8.XTick = [];
            app.UIAxesHiddenD8.YTick = [];
            app.UIAxesHiddenD8.ZTick = [];
            app.UIAxesHiddenD9 = uiaxes(app.DecryptTab);
            app.UIAxesHiddenD9.XTick = [];
            app.UIAxesHiddenD9.YTick = [];
            app.UIAxesHiddenD9.ZTick = [];
            app.UIAxesHiddenD10 = uiaxes(app.DecryptTab);
            app.UIAxesHiddenD10.XTick = [];
            app.UIAxesHiddenD10.YTick = [];
            app.UIAxesHiddenD10.ZTick = [];
            app.UIAxesHiddenD11 = uiaxes(app.DecryptTab);
            app.UIAxesHiddenD11.XTick = [];
            app.UIAxesHiddenD11.YTick = [];
            app.UIAxesHiddenD11.ZTick = [];
            app.UIAxesHiddenD12 = uiaxes(app.DecryptTab);
            app.UIAxesHiddenD12.XTick = [];
            app.UIAxesHiddenD12.YTick = [];
            app.UIAxesHiddenD12.ZTick = [];
            app.SelectMethodDropDownLabel = uilabel(app.DecryptTab);
            app.SelectMethodDropDownLabel.Text = 'Select Method';
            app.SelectMethodDropDown = uidropdown(app.DecryptTab);
            app.SelectMethodDropDown.Items = {'None    ', 'Standard', 'Layers  '};
            app.SelectMethodDropDown.Value = 'None    ';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.OneMonkeyFreeMonkeyLabel = uilabel(app.InfoTab);
            app.OneMonkeyFreeMonkeyLabel.Text = 'One Monkey, Free Monkey';
            app.TextAreaLabel = uilabel(app.InfoTab);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.InfoTab);
            app.TextArea.Value = {'In the eternal strugle for domination against human opressors, we have developed a new mode of communication for our operatives. '; 'The application is capable to encrypt and decrypt secret messages that will help us to overthorw the human race and live free from the tyrany and brutality. '; 'The war has been difficult and we have lost many good monkeys, but through this new application we will be able to persevere and pay back for what those filthy human insects have done to us.'; 'Together we will stand up and fight.'; 'As One monkey. United Monkey. Free Monkey.'; ''; ''};
            app.HomeButtonEncrypt_2 = uibutton(app.InfoTab, 'push');
            app.HomeButtonEncrypt_2.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonEncrypt_2Pushed, true);
            app.HomeButtonEncrypt_2.Text = 'Home';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SpaceMonkeysV7

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end